classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        NhapyEditField           matlab.ui.control.EditField
        NhapyEditFieldLabel      matlab.ui.control.Label
        NhapxEditField           matlab.ui.control.EditField
        NhapxEditFieldLabel      matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        HoacLabel                matlab.ui.control.Label
        KetquaButton             matlab.ui.control.Button
        NhapNEditField           matlab.ui.control.NumericEditField
        NhapNEditFieldLabel      matlab.ui.control.Label
        CanduoiEditField         matlab.ui.control.NumericEditField
        CanduoiEditFieldLabel    matlab.ui.control.Label
        CantrenEditField         matlab.ui.control.NumericEditField
        CantrenEditFieldLabel    matlab.ui.control.Label
        NhaphamsoEditField       matlab.ui.control.EditField
        NhaphamsoEditFieldLabel  matlab.ui.control.Label
        OptionDropDown           matlab.ui.control.DropDown
        OptionDropDownLabel      matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        Tab2                     matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function y = TichPhanHinhThang(app, f, a, b, N)
        h = (b - a)/N;

        fa = f(a);
        fb = f(b);

        S = 0;
        for i = 1:N-1
            xi = a + i*h;
            S = S + f(xi);
        end

        y = h/2 * (fa + fb + 2*S);
        end  
    end
      
    
    methods (Access = private)
        
        function S = TichPhanHinhThangxy(app, x, y)
        N= (x(length(x))-x(1))/(x(2)-x(1));
        if length(x) ~= length(y)
        error('x và y phải có cùng số phần tử');
        end
        if N ~= length(x) - 1
            error('N phải bằng length(x) - 1');
        end
        lenx =length(x);
        h=(x(lenx)-x(1))/N; 

       
        S = y(1)+ y(lenx)+ 2*sum(y(2:(lenx-1)));
        S= h/2*S;
        end  
    end
  methods (Access = private)

    % ===== Simpson 1/3 cho ham so f(x) tren [a,b], N CHAN =====
    function I = TichPhanSimpson13(app, f, a, b, N)
        if mod(N,2) ~= 0
            error('Simpson 1/3: N phai chan');
        end
        h = (b-a)/N;
        x = a:h:b;
        y = f(x);
        I = h/3*(y(1)+y(end) + 4*sum(y(2:2:end-1)) + 2*sum(y(3:2:end-2)));
    end

    % ===== Simpson 3/8 cho ham so f(x) tren [a,b], N chia het cho 3 =====
    function I = TichPhanSimpson38(app, f, a, b, N)
        if mod(N,3) ~= 0
            error('Simpson 3/8: N phai chia het cho 3');
        end
        h = (b-a)/N;
        x = a:h:b;
        y = f(x);

        idx = 2:N;                       % cac chi so noi bo
        mult3 = idx(mod(idx,3)==0);       % chia het cho 3
        not3  = idx(mod(idx,3)~=0);       % khong chia het cho 3

        I = 3*h/8*( y(1) + y(end) + 2*sum(y(mult3)) + 3*sum(y(not3)) );
    end

    % ===== Simpson 1/3 cho du lieu (x,y) - x CACH DEU, N=length(x)-1 CHAN =====
    function I = TichPhanSimpson13xy(app, x, y)
        if length(x) ~= length(y)
            error('x va y phai co cung so phan tu');
        end
        x = x(:); y = y(:);
        N = length(x)-1;
        if mod(N,2) ~= 0
            error('Simpson 1/3 (xy): N=length(x)-1 phai chan');
        end

        h = x(2)-x(1);
        if any(abs(diff(x)-h) > 1e-12)
            error('Simpson (xy): x phai cach deu');
        end

        I = h/3*( y(1)+y(end) + 4*sum(y(2:2:end-1)) + 2*sum(y(3:2:end-2)) );
    end

    % ===== Simpson 3/8 cho du lieu (x,y) - x CACH DEU, N=length(x)-1 chia het cho 3 =====
    function I = TichPhanSimpson38xy(app, x, y)
        if length(x) ~= length(y)
            error('x va y phai co cung so phan tu');
        end
        x = x(:); y = y(:);
        N = length(x)-1;
        if mod(N,3) ~= 0
            error('Simpson 3/8 (xy): N=length(x)-1 phai chia het cho 3');
        end

        h = x(2)-x(1);
        if any(abs(diff(x)-h) > 1e-12)
            error('Simpson (xy): x phai cach deu');
        end

        idx = 2:N;
        mult3 = idx(mod(idx,3)==0);
        not3  = idx(mod(idx,3)~=0);

        I = 3*h/8*( y(1)+y(end) + 2*sum(y(mult3)) + 3*sum(y(not3)) );
    end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KetquaButton
        function Ketqua(app, event)
            %Lay gia tri
            x_str     = app.NhapxEditField.Value;
            y_str     = app.NhapyEditField.Value;
            hamso_str = app.NhaphamsoEditField.Value;
            option    = app.OptionDropDown.Value;

            a = app.CanduoiEditField.Value;
            b = app.CantrenEditField.Value;
            N = app.NhapNEditField.Value;
            %Xu li
            
            x = str2num(x_str);
            y = str2num(y_str);


    switch option
    case 'Hinh thang'
        if ~isempty(x) && ~isempty(y)
            S = app.TichPhanHinhThangxy(x, y);
            xplot = x; yplot = y;
        else
            fx = str2func("@(x)" + hamso_str);
            S  = app.TichPhanHinhThang(fx, a, b, N);
            xplot = linspace(a, b, N+1);
            yplot = fx(xplot);
        end

    case 'Simpson1/3'
        if ~isempty(x) && ~isempty(y)
            % Simpson 1/3 voi du lieu: N = length(x)-1 phai chan
            try
                S = app.TichPhanSimpson13xy(x, y);
            catch ME
                uialert(app.UIFigure, ME.message, 'Loi Simpson 1/3 (xy)');
                return;
            end
            xplot = x; yplot = y;
        else
            if mod(N,2) ~= 0
                uialert(app.UIFigure,'Simpson 1/3 can N chan','Sai N');
                return;
            end
            fx = str2func("@(x)" + hamso_str);
            try
                S = app.TichPhanSimpson13(fx, a, b, N);
            catch ME
                uialert(app.UIFigure, ME.message, 'Loi Simpson 1/3');
                return;
            end
            xplot = linspace(a, b, N+1);
            yplot = fx(xplot);
        end

    case 'Simpson3/8'
        if ~isempty(x) && ~isempty(y)
            % Simpson 3/8 voi du lieu: N = length(x)-1 chia het cho 3
            try
                S = app.TichPhanSimpson38xy(x, y);
            catch ME
                uialert(app.UIFigure, ME.message, 'Loi Simpson 3/8 (xy)');
                return;
            end
            xplot = x; yplot = y;
        else
            if mod(N,3) ~= 0
                uialert(app.UIFigure,'Simpson 3/8 can N chia het cho 3','Sai N');
                return;
            end
            fx = str2func("@(x)" + hamso_str);
            try
                S = app.TichPhanSimpson38(fx, a, b, N);
            catch ME
                uialert(app.UIFigure, ME.message, 'Loi Simpson 3/8');
                return;
            end
            xplot = linspace(a, b, N+1);
            yplot = fx(xplot);
        end
end

        % ===== VE DO THI (giu style cua ban) =====
        cla(app.UIAxes);
        hold(app.UIAxes, 'on');

        plot(app.UIAxes, xplot, yplot, 'LineWidth', 2);
        area(app.UIAxes, xplot, yplot, 'FaceAlpha', 0.3, 'EdgeColor', 'none');

        % Ve truc Ox theo dung [a,b]
        plot(app.UIAxes, [a b], [0 0], 'k', 'LineWidth', 1);

        grid(app.UIAxes, 'on');
        hold(app.UIAxes, 'off');

        app.Label.Text = sprintf('I = %.6f', S);

            
        end

        % Value changed function: OptionDropDown
        function ValueChangedFcn(app, event)
        
               
        end

        % Value changed function: NhapxEditField
        function Nhapx(app, event)
           
            
        end

        % Value changed function: NhapyEditField
        function Nhapy(app, event)
            
            
        end

        % Value changed function: NhaphamsoEditField
        function Nhaphamso(app, event)
           
            
        end

        % Value changed function: CantrenEditField
        function Cantren(app, event)
           
            
        end

        % Value changed function: NhapNEditField
        function NhapN(app, event)
          
            
        end

        % Value changed function: CanduoiEditField
        function Canduoi(app, event)
       
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Phan dien tich ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OptionDropDownLabel = uilabel(app.Tab);
            app.OptionDropDownLabel.Text = 'Option :';
            app.OptionDropDown = uidropdown(app.Tab);
            app.OptionDropDown.Items = {'Hinh thang', 'Simpson1/3', 'Simpson3/8'};
            app.OptionDropDown.ValueChangedFcn = createCallbackFcn(app, @ValueChangedFcn, true);
            app.OptionDropDown.Value = 'Hinh thang';
            app.NhaphamsoEditFieldLabel = uilabel(app.Tab);
            app.NhaphamsoEditFieldLabel.Text = 'Nhap ham so :';
            app.NhaphamsoEditField = uieditfield(app.Tab, 'text');
            app.NhaphamsoEditField.ValueChangedFcn = createCallbackFcn(app, @Nhaphamso, true);
            app.NhaphamsoEditField.Value = '1./x';
            app.CantrenEditFieldLabel = uilabel(app.Tab);
            app.CantrenEditFieldLabel.Text = 'Can tren : ';
            app.CantrenEditField = uieditfield(app.Tab, 'numeric');
            app.CantrenEditField.ValueChangedFcn = createCallbackFcn(app, @Cantren, true);
            app.CantrenEditField.Value = 5;
            app.CanduoiEditFieldLabel = uilabel(app.Tab);
            app.CanduoiEditFieldLabel.Text = 'Can duoi :';
            app.CanduoiEditField = uieditfield(app.Tab, 'numeric');
            app.CanduoiEditField.ValueChangedFcn = createCallbackFcn(app, @Canduoi, true);
            app.CanduoiEditField.Value = 1;
            app.NhapNEditFieldLabel = uilabel(app.Tab);
            app.NhapNEditFieldLabel.Text = 'Nhap N:';
            app.NhapNEditField = uieditfield(app.Tab, 'numeric');
            app.NhapNEditField.ValueChangedFcn = createCallbackFcn(app, @NhapN, true);
            app.NhapNEditField.Value = 4;
            app.KetquaButton = uibutton(app.Tab, 'push');
            app.KetquaButton.ButtonPushedFcn = createCallbackFcn(app, @Ketqua, true);
            app.KetquaButton.Text = 'Ket qua';
            app.HoacLabel = uilabel(app.Tab);
            app.HoacLabel.Text = 'Hoac :';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '  ';
            app.NhapxEditFieldLabel = uilabel(app.Tab);
            app.NhapxEditFieldLabel.Text = 'Nhap x: ';
            app.NhapxEditField = uieditfield(app.Tab, 'text');
            app.NhapxEditField.ValueChangedFcn = createCallbackFcn(app, @Nhapx, true);
            app.NhapyEditFieldLabel = uilabel(app.Tab);
            app.NhapyEditFieldLabel.Text = 'Nhap y: ';
            app.NhapyEditField = uieditfield(app.Tab, 'text');
            app.NhapyEditField.ValueChangedFcn = createCallbackFcn(app, @Nhapy, true);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end