classdef hoiquy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HoiquytuyentinhPanel            matlab.ui.container.Panel
        KetquadudoanEditField           matlab.ui.control.NumericEditField
        KetquadudoanEditFieldLabel      matlab.ui.control.Label
        NhapgiatridudoanEditField       matlab.ui.control.NumericEditField
        NhapgiatridudoanEditFieldLabel  matlab.ui.control.Label
        KetquaphuongtrinhEditField      matlab.ui.control.EditField
        KetquaphuongtrinhEditFieldLabel  matlab.ui.control.Label
        PhuongPhapListBox               matlab.ui.control.ListBox
        PhuongPhapListBoxLabel          matlab.ui.control.Label
        yEditField                      matlab.ui.control.EditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.EditField
        xEditFieldLabel                 matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xEditField.Value = '1:9';
            app.yEditField.Value = '1, 1.5, 2, 3, 4, 5, 8, 10, 13';
        
            % Phương pháp mặc định
            app.PhuongPhapListBox.Value = 'Tuyen tinh';
        
            % Giá trị dự đoán mặc định
            app.NhapgiatridudoanEditField.Value = 6;
        
            % Xóa kết quả cũ
            app.KetquaphuongtrinhEditField.Value = '';
            app.KetquadudoanEditField.Value = 0;
        
            % Chuẩn bị trục
            cla(app.UIAxes)
            grid(app.UIAxes,'on')
            title(app.UIAxes,'Do thi hoi quy')
            xlabel(app.UIAxes,'X')
            ylabel(app.UIAxes,'Y')


        end

        % Button pushed function: RunButton
        function Run(app, event)
            %% ============= 1. thiết lập ban đầu =========
            x = str2num(app.xEditField.Value); %#ok<ST2NM>
            y = str2num(app.yEditField.Value); %#ok<ST2NM>
        
            if isempty(x) || isempty(y)
                uialert(app.UIFigure,'Chưa nhập x hoặc y','Lỗi');
                return
            end
        
            if length(x) ~= length(y)
                uialert(app.UIFigure,'x và y phải cùng số phần tử','Lỗi');
                return
            end
        
            %% ===== 2. CHỌN PHƯƠNG PHÁP & GỌI HÀM =====
            method = app.PhuongPhapListBox.Value;
        
            switch method
                case 'Tuyen tinh'
                    [a1, a0, ~, eqn] = regressionLinear(x, y);
        
                case 'Ham mu'
                    try
                        [a, b, ~, eqn] = regressionPower(x, y);
                    catch ME
                        uialert(app.UIFigure, ME.message, 'Lỗi');
                        return
                    end
        
                case 'Ham mu (e^x)'
                    try
                        [a, b, ~, eqn] = regressionExp(x, y);
                    catch ME
                        uialert(app.UIFigure, ME.message, 'Lỗi');
                        return
                    end
            end
        
            %% ===== 3. HIỂN THỊ PHƯƠNG TRÌNH =====
            app.KetquaphuongtrinhEditField.Value = eqn;
        
            %% ===== 4. VẼ ĐỒ THỊ =====
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
        
            plot(app.UIAxes, x, y, 'bo', 'MarkerFaceColor','b')
        
            xx = linspace(min(x), max(x), 200);
        
            switch method
                case 'Tuyen tinh'
                    yy = a1*xx + a0;
                case 'Ham mu'
                    yy = a*xx.^b;
                case 'Ham mu (e^x)'
                    yy = a * exp(b * xx);
            end
        
            plot(app.UIAxes, xx, yy, 'r', 'LineWidth', 2)
        
            grid(app.UIAxes,'on')
            
            hold(app.UIAxes,'off')
        
            %% ===== 5. DỰ ĐOÁN =====
            x0 = app.NhapgiatridudoanEditField.Value;
        
            switch method
                case 'Tuyen tinh'
                    y0 = a1*x0 + a0;
        
                case 'Ham mu'
                    if x0 <= 0
                        uialert(app.UIFigure,'x0 phải > 0','Lỗi');
                        return
                    end
                    y0 = a*x0^b;
        
                case 'Ham mu (e^x)'
                    y0 = a * exp(b * x0);
            end
        
            app.KetquadudoanEditField.Value = y0;
        
            %% ===== 6. VẼ ĐIỂM DỰ ĐOÁN =====
            hold(app.UIAxes,'on')
            plot(app.UIAxes, x0, y0, 'ro', 'MarkerSize', 8, 'LineWidth', 2)
            legend(app.UIAxes, {'Dữ liệu','Đường Hồi quy', ...
                'Điểm dự đoán'}, 'Location','best')
            hold(app.UIAxes,'off')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HoiquytuyentinhPanel = uipanel(app.UIFigure);
            app.HoiquytuyentinhPanel.Title = 'Hoi quy tuyen tinh';
            app.UIAxes = uiaxes(app.HoiquytuyentinhPanel);
            title(app.UIAxes, 'Do thi hoi quy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.HoiquytuyentinhPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunButton.Text = 'Run';
            app.xEditFieldLabel = uilabel(app.HoiquytuyentinhPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.HoiquytuyentinhPanel, 'text');
            app.xEditField.Value = '1';
            app.yEditFieldLabel = uilabel(app.HoiquytuyentinhPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.HoiquytuyentinhPanel, 'text');
            app.PhuongPhapListBoxLabel = uilabel(app.HoiquytuyentinhPanel);
            app.PhuongPhapListBoxLabel.Text = 'Phuong Phap';
            app.PhuongPhapListBox = uilistbox(app.HoiquytuyentinhPanel);
            app.PhuongPhapListBox.Items = {'Tuyen tinh', 'Ham mu', 'Ham mu (e^x)'};
            app.PhuongPhapListBox.Value = 'Ham mu';
            app.KetquaphuongtrinhEditFieldLabel = uilabel(app.HoiquytuyentinhPanel);
            app.KetquaphuongtrinhEditFieldLabel.Text = 'Ket qua phuong trinh';
            app.KetquaphuongtrinhEditField = uieditfield(app.HoiquytuyentinhPanel, 'text');
            app.NhapgiatridudoanEditFieldLabel = uilabel(app.HoiquytuyentinhPanel);
            app.NhapgiatridudoanEditFieldLabel.Text = 'Nhap gia tri du doan';
            app.NhapgiatridudoanEditField = uieditfield(app.HoiquytuyentinhPanel, 'numeric');
            app.KetquadudoanEditFieldLabel = uilabel(app.HoiquytuyentinhPanel);
            app.KetquadudoanEditFieldLabel.Text = 'Ket qua du doan';
            app.KetquadudoanEditField = uieditfield(app.HoiquytuyentinhPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hoiquy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end