classdef Dao_Ham < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        ohmTab                   matlab.ui.container.Tab
        NTHCHNHPHNGPHPTNHLabel   matlab.ui.control.Label
        StatusLamp               matlab.ui.control.Lamp
        TrngthiLabel             matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        EditFieldResult          matlab.ui.control.NumericEditField
        Gia_tri_dao_ham          matlab.ui.control.Label
        ButtonCalc               matlab.ui.control.Button
        ChnchcnngPanel           matlab.ui.container.Panel
        DropDownOrder            matlab.ui.control.DropDown
        SaisLabel                matlab.ui.control.Label
        DropDownMethod           matlab.ui.control.DropDown
        PhngphpLabel             matlab.ui.control.Label
        PanelData                matlab.ui.container.Panel
        EditFieldX_Data          matlab.ui.control.NumericEditField
        TiimxLabel_2             matlab.ui.control.Label
        EditFieldArrY            matlab.ui.control.EditField
        MngytngngLabel           matlab.ui.control.Label
        EditFieldArrX            matlab.ui.control.EditField
        MngxcchnhaubiducchLabel  matlab.ui.control.Label
        PanelFunction            matlab.ui.container.Panel
        EditFieldH               matlab.ui.control.NumericEditField
        BcnhyhLabel              matlab.ui.control.Label
        EditFieldFunc            matlab.ui.control.EditField
        HmfxLabel                matlab.ui.control.Label
        EditFieldX_Func          matlab.ui.control.NumericEditField
        TiimxLabel               matlab.ui.control.Label
        InputPanel               matlab.ui.container.Panel
        DropDownInputMode        matlab.ui.control.DropDown
        ChnchnhpDropDownLabel    matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonCalc
        function ButtonCalcPushed(app, event)
           

    app.StatusLamp.Color = [1.00 0.84 0.00]; % Màu vàng (Gold)
    drawnow; % Lệnh bắt buộc để cập nhật giao diện ngay lập tức
    
    mode = app.DropDownInputMode.Value;
    method = app.DropDownMethod.Value;
    order = app.DropDownOrder.Value;
    result = 0;
    
    try
        % ---------------------------------------------------------
        % NHÁNH 1: TÍNH TOÁN DỰA TRÊN HÀM SỐ f(x)
        % ---------------------------------------------------------
        if strcmp(mode, 'Hàm số f(x)')
            % Lấy dữ liệu đầu vào
            strFunc = app.EditFieldFunc.Value;
            x0 = app.EditFieldX_Func.Value;
            h = app.EditFieldH.Value;
            
            % Chuyển chuỗi thành hàm số (Anonymous Function)
            % Tham khảo cách dùng str2func trong giáo trình trang 62 [cite: 1704]
            try
                f = str2func(['@(x) ' strFunc]); 
            catch
                error('Cú pháp hàm số không hợp lệ. Ví dụ đúng: sin(x), x^2 + 1');
            end
            
            % Áp dụng công thức tính đạo hàm
            if contains(method, 'Tiến') % Forward
                if strcmp(order, 'O(h)')
                    % Công thức xấp xỉ tiến O(h) [cite: 1007]
                    result = (f(x0 + h) - f(x0)) / h;
                else
                    % Công thức xấp xỉ tiến O(h^2) [cite: 1011]
                    result = (-3*f(x0) + 4*f(x0 + h) - f(x0 + 2*h)) / (2*h);
                end
                
            elseif contains(method, 'Lùi') % Backward
                if strcmp(order, 'O(h)')
                    % Công thức xấp xỉ lùi O(h) [cite: 1010]
                    result = (f(x0) - f(x0 - h)) / h;
                else
                    % Công thức xấp xỉ lùi O(h^2) [cite: 1014]
                    result = (f(x0 - 2*h) - 4*f(x0 - h) + 3*f(x0)) / (2*h);
                end
                
            else % Trung tâm (Central)
                 % Công thức xấp xỉ trung tâm (thường mặc định O(h^2)) [cite: 1001]
                 result = (f(x0 + h) - f(x0 - h)) / (2*h);
            end
            
            % Chuẩn bị dữ liệu để vẽ đồ thị
            x_plot = linspace(x0 - 5*h, x0 + 5*h, 100);
            y_plot = f(x_plot);
            y0 = f(x0);
            
        % ---------------------------------------------------------
        % NHÁNH 2: TÍNH TOÁN DỰA TRÊN DỮ LIỆU MẢNG (RỜI RẠC)
        % ---------------------------------------------------------
        else
            % Chuyển chuỗi nhập vào thành mảng số
            arrX = str2num(app.EditFieldArrX.Value); %#ok<ST2NM>
            arrY = str2num(app.EditFieldArrY.Value); %#ok<ST2NM>
            x0 = app.EditFieldX_Data.Value;
            
            % Kiểm tra tính hợp lệ của dữ liệu
            if isempty(arrX) || isempty(arrY)
                error('Dữ liệu mảng X hoặc Y đang trống hoặc sai định dạng.');
            end
            if length(arrX) ~= length(arrY)
                error('Độ dài mảng X và mảng Y phải bằng nhau.');
            end
            
            % Tìm vị trí (index) của x0 trong mảng X
            idx = find(arrX == x0, 1);
            if isempty(idx)
                error('Giá trị x cần tính không tồn tại trong mảng dữ liệu X.');
            end
            
            % Tự động tính bước h dựa trên các phần tử lân cận
            if idx < length(arrX)
                h = arrX(idx+1) - arrX(idx);
            elseif idx > 1
                h = arrX(idx) - arrX(idx-1);
            else
                h = 1; % Giá trị mặc định nếu mảng chỉ có 1 phần tử (tránh chia cho 0)
            end 
            
            y_curr = arrY(idx);
            
            % Tính toán dựa trên Index mảng
            if contains(method, 'Tiến')
                if idx >= length(arrX)
                     error('Không đủ điểm dữ liệu phía sau để tính Xấp xỉ Tiến.');
                end
                % Công thức rời rạc O(h): (y_{i+1} - y_i) / h
                result = (arrY(idx+1) - y_curr) / h;
                
            elseif contains(method, 'Lùi')
                if idx <= 1
                     error('Không đủ điểm dữ liệu phía trước để tính Xấp xỉ Lùi.');
                end
                % Công thức rời rạc O(h): (y_i - y_{i-1}) / h
                result = (y_curr - arrY(idx-1)) / h;
                
            else % Trung tâm
                if idx <= 1 || idx >= length(arrX)
                    error('Cần điểm dữ liệu ở cả hai phía để tính Xấp xỉ Trung tâm.');
                end
                % Công thức rời rạc O(h^2): (y_{i+1} - y_{i-1}) / (2h)
                result = (arrY(idx+1) - arrY(idx-1)) / (2*h);
            end
            
            % Chuẩn bị dữ liệu để vẽ
            x_plot = arrX;
            y_plot = arrY;
            y0 = y_curr;
        end
        
        % ---------------------------------------------------------
        % 3. HIỂN THỊ KẾT QUẢ VÀ VẼ ĐỒ THỊ
        % ---------------------------------------------------------
        
        % Cập nhật kết quả vào ô vuông (Numeric Edit Field)
        app.EditFieldResult.Value = result;
        
        % Xử lý đồ thị trên UIAxes
        cla(app.UIAxes);         % Xóa đồ thị cũ
        hold(app.UIAxes, 'on');  % Giữ lại các nét vẽ chồng lên nhau
        
        % Vẽ đường hàm số hoặc các điểm dữ liệu
        if strcmp(mode, 'Hàm số f(x)')
            plot(app.UIAxes, x_plot, y_plot, 'b-', 'LineWidth', 1.5, 'DisplayName', 'Hàm f(x)');
        else
            plot(app.UIAxes, x_plot, y_plot, 'bo--', 'LineWidth', 1, 'DisplayName', 'Dữ liệu mảng');
        end
        
        % Vẽ điểm đang tính toán (màu đỏ đậm)
        plot(app.UIAxes, x0, y0, 'r*', 'MarkerSize', 10, 'LineWidth', 2, 'DisplayName', 'Điểm tính đạo hàm');
        
        % Vẽ đường tiếp tuyến: y = f'(x0)*(x - x0) + f(x0)
        % Tạo khoảng vẽ ngắn xung quanh x0 để minh họa độ dốc
        x_tangent = linspace(x0 - h, x0 + h, 50); 
        y_tangent = result * (x_tangent - x0) + y0;
        plot(app.UIAxes, x_tangent, y_tangent, 'r-', 'LineWidth', 2, 'DisplayName', 'Tiếp tuyến (Gần đúng)');
        
        % Trang trí đồ thị
        title(app.UIAxes, ['Minh họa đạo hàm tại x = ' num2str(x0)]);
        xlabel(app.UIAxes, 'x');
        ylabel(app.UIAxes, 'f(x)');
        grid(app.UIAxes, 'on');
        legend(app.UIAxes);
        hold(app.UIAxes, 'off');
        
        app.StatusLamp.Color = [0.00 1.00 0.00];
        
    catch ME
        app.StatusLamp.Color = [1.00 0.00 0.00];
        % Hiển thị thông báo lỗi chuyên nghiệp bằng uialert
        uialert(app.UIFigure, ME.message, 'Lỗi Tính Toán', 'Icon', 'error');
        % Reset ô kết quả về 0 hoặc rỗng nếu muốn
        app.EditFieldResult.Value = 0; 
    end
        
        end

        % Value changed function: DropDownInputMode
        function DropDownInputModeValueChanged(app, event)
          
    value = app.DropDownInputMode.Value;
    
   
    if strcmp(value, 'Hàm số f(x)')
        app.PanelFunction.Visible = 'on'; % Hiện khung nhập hàm
        app.PanelData.Visible = 'off';    % Ẩn khung nhập mảng
    else
        app.PanelFunction.Visible = 'off'; % Ẩn khung nhập hàm
        app.PanelData.Visible = 'on';      % Hiện khung nhập mảng
    end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.UIAxes = uiaxes(app.ohmTab);
            title(app.UIAxes, 'Đồ thị hàm số và tiếp tuyến')
            xlabel(app.UIAxes, 'Giá trị của x')
            ylabel(app.UIAxes, 'Giá trị hàm số f(x)')
            zlabel(app.UIAxes, 'Z')
            app.InputPanel = uipanel(app.ohmTab);
            app.InputPanel.Title = 'Input';
            app.ChnchnhpDropDownLabel = uilabel(app.InputPanel);
            app.ChnchnhpDropDownLabel.Text = 'Chọn chế độ nhập:';
            app.DropDownInputMode = uidropdown(app.InputPanel);
            app.DropDownInputMode.Items = {'Hàm số f(x)', 'Dữ liệu mảng (x, y)'};
            app.DropDownInputMode.ValueChangedFcn = createCallbackFcn(app, @DropDownInputModeValueChanged, true);
            app.DropDownInputMode.Value = 'Hàm số f(x)';
            app.PanelFunction = uipanel(app.ohmTab);
            app.PanelFunction.Title = 'Nhập các giá trị sau:';
            app.TiimxLabel = uilabel(app.PanelFunction);
            app.TiimxLabel.Text = 'Tại điểm x =';
            app.EditFieldX_Func = uieditfield(app.PanelFunction, 'numeric');
            app.EditFieldX_Func.Value = 3;
            app.HmfxLabel = uilabel(app.PanelFunction);
            app.HmfxLabel.Text = 'Hàm f(x):';
            app.EditFieldFunc = uieditfield(app.PanelFunction, 'text');
            app.EditFieldFunc.Value = 'sin(x)';
            app.BcnhyhLabel = uilabel(app.PanelFunction);
            app.BcnhyhLabel.Text = 'Bước nhảy h =';
            app.EditFieldH = uieditfield(app.PanelFunction, 'numeric');
            app.EditFieldH.Value = 0.1;
            app.PanelData = uipanel(app.ohmTab);
            app.PanelData.Title = 'Nhập các giá trị sau:';
            app.PanelData.Visible = 'off';
            app.MngxcchnhaubiducchLabel = uilabel(app.PanelData);
            app.MngxcchnhaubiducchLabel.Text = 'Mảng x:';
            app.EditFieldArrX = uieditfield(app.PanelData, 'text');
            app.EditFieldArrX.Value = '1 2 3 4';
            app.MngytngngLabel = uilabel(app.PanelData);
            app.MngytngngLabel.Text = 'Mảng y:';
            app.EditFieldArrY = uieditfield(app.PanelData, 'text');
            app.EditFieldArrY.Value = '6 7 8 9';
            app.TiimxLabel_2 = uilabel(app.PanelData);
            app.TiimxLabel_2.Text = 'Tại điểm x =';
            app.EditFieldX_Data = uieditfield(app.PanelData, 'numeric');
            app.EditFieldX_Data.Value = 3;
            app.ChnchcnngPanel = uipanel(app.ohmTab);
            app.ChnchcnngPanel.Title = 'Chọn chức năng';
            app.PhngphpLabel = uilabel(app.ChnchcnngPanel);
            app.PhngphpLabel.Text = 'Phương pháp:';
            app.DropDownMethod = uidropdown(app.ChnchcnngPanel);
            app.DropDownMethod.Items = {'Tiến', 'Lùi', 'Trung tâm'};
            app.DropDownMethod.Value = 'Tiến';
            app.SaisLabel = uilabel(app.ChnchcnngPanel);
            app.SaisLabel.Text = 'Sai số:';
            app.DropDownOrder = uidropdown(app.ChnchcnngPanel);
            app.DropDownOrder.Items = {'O(h)', 'O(h^2)'};
            app.DropDownOrder.Value = 'O(h)';
            app.ButtonCalc = uibutton(app.ohmTab, 'push');
            app.ButtonCalc.ButtonPushedFcn = createCallbackFcn(app, @ButtonCalcPushed, true);
            app.ButtonCalc.Text = 'TÍNH ĐẠO HÀM';
            app.Gia_tri_dao_ham = uilabel(app.ohmTab);
            app.Gia_tri_dao_ham.Text = 'Giá trị đạo hàm (y''):';
            app.EditFieldResult = uieditfield(app.ohmTab, 'numeric');
            app.EditFieldResult.ValueDisplayFormat = '%.4f';
            app.EditFieldResult.Editable = 'off';
            app.Image = uiimage(app.ohmTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo-hcmus-new.jpg');
            app.TrngthiLabel = uilabel(app.ohmTab);
            app.TrngthiLabel.Text = 'Trạng thái';
            app.StatusLamp = uilamp(app.ohmTab);
            app.NTHCHNHPHNGPHPTNHLabel = uilabel(app.ohmTab);
            app.NTHCHNHPHNGPHPTNHLabel.Text = {'ĐỒ ÁN THỰC HÀNH'; 'PHƯƠNG PHÁP TÍNH'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dao_Ham

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end