classdef appNoiSuy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DLY                           matlab.ui.control.EditField
        NhpdliuyEditFieldLabel        matlab.ui.control.Label
        DLX                           matlab.ui.control.EditField
        NhpdliuxLabel                 matlab.ui.control.Label
        KQDTNS                        matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel   matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        KQNS                          matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel       matlab.ui.control.Label
        GTCNS                         matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel  matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        PPNS                          matlab.ui.control.EditField
        PhngphpnisuyEditFieldLabel    matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

   
            % Khởi tạo các biến kết quả
            poly_str = ''; 
            interp_value = 0;
            
            try
                % 1. Đọc và Kiểm tra Dữ liệu
                x = str2num(app.DLX.Value); 
                y = str2num(app.DLY.Value); 
                
                if isempty(x) || isempty(y)
                    uialert(app.UIFigure,'Chưa nhập dữ liệu x hoặc y','Lỗi Dữ liệu'); return;
                end
                n = length(x);
                if n ~= length(y)
                    uialert(app.UIFigure,'x và y phải cùng số phần tử','Lỗi Dữ liệu'); return;
                end
                if n < 2
                    uialert(app.UIFigure,'Phải có ít nhất 2 điểm dữ liệu','Lỗi Dữ liệu'); return;
                end
                
                % Kiểm tra trùng lặp x
                if length(unique(x)) ~= n
                     uialert(app.UIFigure,'Các giá trị x không được trùng nhau!','Lỗi Tính toán'); return;
                end
        
                method = app.DropDown.Value; 
                x_interp = app.GTCNS.Value;
                
                %% ===== 2. TÍNH TOÁN GIÁ TRỊ NỘI SUY (Tại điểm x cần tìm) =====
                % Dùng hàm polyfit để tìm hệ số đa thức: p(1)*x^N + p(2)*x^(N-1) + ... + p(N+1)
                % Đa thức nội suy đi qua n điểm sẽ có bậc là n-1
                p_coeffs = polyfit(x, y, n-1);
                
                % Tính giá trị tại điểm x_interp bằng hàm polyval
                interp_value = polyval(p_coeffs, x_interp);
                
                %% ===== 3. TẠO CHUỖI ĐA THỨC HIỂN THỊ (P(x) = ...) =====
                % Phần này chuyển đổi mảng hệ số p_coeffs thành chuỗi đẹp mắt
                poly_str = 'P(x) = ';
                degree = length(p_coeffs) - 1;
                
                for i = 1:length(p_coeffs)
                    coef = p_coeffs(i);
                    power = degree - (i-1);
                    
                    % Chỉ hiển thị các hệ số đáng kể (khác 0)
                    if abs(coef) > 1e-5 
                        
                        % Xử lý dấu cộng/trừ
                        if i > 1 
                            if coef >= 0
                                poly_str = [poly_str ' + ']; %#ok<*AGROW>
                            else
                                poly_str = [poly_str ' - '];
                            end
                        elseif coef < 0 % Số đầu tiên âm
                            poly_str = [poly_str '-'];
                        end
                        
                        % Xử lý giá trị số (Lấy trị tuyệt đối)
                        poly_str = [poly_str num2str(abs(coef), '%.4g')];
                        
                        % Xử lý biến x và số mũ
                        if power == 1
                            poly_str = [poly_str '*x'];
                        elseif power > 1
                            poly_str = [poly_str '*x^' num2str(power)];
                        end
                    end
                end
                
                % Trường hợp đa thức bằng 0 hoặc rỗng
                if strcmp(poly_str, 'P(x) = ')
                    poly_str = 'P(x) = 0';
                end
        
                %% ===== 4. HIỂN THỊ KẾT QUẢ =====
                app.KQNS.Value = interp_value; 
                app.KQDTNS.Value = poly_str;       
                
                %% ===== 5. VẼ ĐỒ THỊ =====
                cla(app.UIAxes);
                hold(app.UIAxes,'on');
                
                % Tạo dữ liệu vẽ đường cong mịn
                x_plot = linspace(min(x), max(x), 100);
                y_plot = polyval(p_coeffs, x_plot);
                
                % Vẽ đường nội suy
                plot(app.UIAxes, x_plot, y_plot, 'r-', 'LineWidth', 2, 'DisplayName', ['Nội suy ' method]);
                
                % Vẽ các điểm nút (Dữ liệu gốc)
                plot(app.UIAxes, x, y, 'b.', 'MarkerSize', 20, 'DisplayName', 'Điểm nút');
                
                % Vẽ điểm kết quả nội suy
                plot(app.UIAxes, x_interp, interp_value, 'g*', 'MarkerSize', 10, 'LineWidth', 2, 'DisplayName', 'Kết quả');
                
                hold(app.UIAxes,'off');
                title(app.UIAxes, ['Đồ thị nội suy ' method]);
                xlabel(app.UIAxes, 'X');
                ylabel(app.UIAxes, 'Y');
                legend(app.UIAxes, 'Location', 'best');
                grid(app.UIAxes, 'on');
                
            catch ME
                uialert(app.UIFigure, ['Đã xảy ra lỗi: ', ME.message], 'Lỗi Tính toán');
                app.KQNS.Value = 0; 
                app.KQDTNS.Value = 'Lỗi';
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị hàm số nội suy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PhngphpnisuyEditFieldLabel = uilabel(app.UIFigure);
            app.PhngphpnisuyEditFieldLabel.Text = 'Phương pháp nội suy';
            app.PPNS = uieditfield(app.UIFigure, 'text');
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'NewTon', 'Larange'};
            app.DropDown.Value = 'NewTon';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.UIFigure);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.GTCNS = uieditfield(app.UIFigure, 'numeric');
            app.GTCNS.Value = 0.14;
            app.KtqunisuyEditFieldLabel = uilabel(app.UIFigure);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KQNS = uieditfield(app.UIFigure, 'numeric');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.KtquathcnisuyEditFieldLabel = uilabel(app.UIFigure);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KQDTNS = uieditfield(app.UIFigure, 'text');
            app.NhpdliuxLabel = uilabel(app.UIFigure);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x';
            app.DLX = uieditfield(app.UIFigure, 'text');
            app.DLX.Value = '0.1 0.2 0.3  0.4';
            app.NhpdliuyEditFieldLabel = uilabel(app.UIFigure);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.DLY = uieditfield(app.UIFigure, 'text');
            app.DLY.Value = '0.09983 0.19867 0.29552 0.38942';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appNoiSuy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end