classdef project_ppt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        NghiemTab                    matlab.ui.container.Tab
        EndEditField                 matlab.ui.control.NumericEditField
        EndEditFieldLabel            matlab.ui.control.Label
        StartEditField               matlab.ui.control.NumericEditField
        StartEditFieldLabel          matlab.ui.control.Label
        nEditField                   matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel          matlab.ui.control.Label
        resultEditField              matlab.ui.control.NumericEditField
        KtquEditFieldLabel           matlab.ui.control.Label
        ppListBox                    matlab.ui.control.ListBox
        PhngphpListBoxLabel          matlab.ui.control.Label
        errorEditField               matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel  matlab.ui.control.Label
        NhpkhongphnlynghimABLabel    matlab.ui.control.Label
        BEditField                   matlab.ui.control.NumericEditField
        BEditFieldLabel              matlab.ui.control.Label
        AEditField                   matlab.ui.control.NumericEditField
        AEditFieldLabel              matlab.ui.control.Label
        inputEditField               matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel    matlab.ui.control.Label
        RUNButton                    matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        NisuyTab                     matlab.ui.container.Tab
        PhngphpLabel                 matlab.ui.control.Label
        GitrnisuyLabel               matlab.ui.control.Label
        athcnisuyLabel               matlab.ui.control.Label
        DLY                          matlab.ui.control.EditField
        NhpdliuyEditFieldLabel       matlab.ui.control.Label
        DLX                          matlab.ui.control.EditField
        NhpdliuxLabel                matlab.ui.control.Label
        KQNS                         matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel      matlab.ui.control.Label
        KQDTNS                       matlab.ui.control.EditField
        RunButton_2                  matlab.ui.control.Button
        GTCNS                        matlab.ui.control.NumericEditField
        DropDown                     matlab.ui.control.DropDown
        UIAxes_5                     matlab.ui.control.UIAxes
        HiquyTab                     matlab.ui.container.Tab
        yLabel                       matlab.ui.control.Label
        xLabel                       matlab.ui.control.Label
        DonktquLabel                 matlab.ui.control.Label
        KetquaphuongtrinhEditField   matlab.ui.control.EditField
        KetquaphuongtrinhEditField_2Label  matlab.ui.control.Label
        PhuongPhapListBox            matlab.ui.control.ListBox
        PhuongPhapListBox_2Label     matlab.ui.control.Label
        yEditField                   matlab.ui.control.EditField
        yEditField_2Label            matlab.ui.control.Label
        xEditField                   matlab.ui.control.EditField
        xEditField_2Label            matlab.ui.control.Label
        KetquadudoanEditField        matlab.ui.control.NumericEditField
        NhapgiatridudoanEditField    matlab.ui.control.NumericEditField
        RunButton                    matlab.ui.control.Button
        UIAxes_4                     matlab.ui.control.UIAxes
        ohmTab                       matlab.ui.container.Tab
        TabGroup_2                   matlab.ui.container.TabGroup
        ohmTab_2                     matlab.ui.container.Tab
        NTHCHNHPHNGPHPTNHLabel       matlab.ui.control.Label
        StatusLamp                   matlab.ui.control.Lamp
        TrngthiLabel                 matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        EditFieldResult              matlab.ui.control.NumericEditField
        Gia_tri_dao_ham              matlab.ui.control.Label
        ButtonCalc                   matlab.ui.control.Button
        ChnchcnngPanel               matlab.ui.container.Panel
        DropDownOrder                matlab.ui.control.DropDown
        SaisLabel                    matlab.ui.control.Label
        DropDownMethod               matlab.ui.control.DropDown
        PhngphpLabel_2               matlab.ui.control.Label
        PanelFunction_2              matlab.ui.container.Panel
        PanelData_2                  matlab.ui.container.Panel
        EditFieldArrX                matlab.ui.control.EditField
        MngxcchnhaubiducchLabel      matlab.ui.control.Label
        EditFieldX_Data              matlab.ui.control.NumericEditField
        TiimxLabel_2                 matlab.ui.control.Label
        EditFieldArrY                matlab.ui.control.EditField
        MngytngngLabel               matlab.ui.control.Label
        EditFieldH                   matlab.ui.control.NumericEditField
        BcnhyhLabel                  matlab.ui.control.Label
        EditFieldFunc                matlab.ui.control.EditField
        HmfxLabel                    matlab.ui.control.Label
        EditFieldX_Func              matlab.ui.control.NumericEditField
        TiimxLabel                   matlab.ui.control.Label
        InputPanel                   matlab.ui.container.Panel
        DropDownInputMode            matlab.ui.control.DropDown
        ChnchnhpDropDownLabel        matlab.ui.control.Label
        UIAxes_7                     matlab.ui.control.UIAxes
        TchphnTab                    matlab.ui.container.Tab
        PanelFunction                matlab.ui.container.Panel
        CantrenEditField             matlab.ui.control.NumericEditField
        CantrenEditFieldLabel        matlab.ui.control.Label
        NhapNEditField               matlab.ui.control.NumericEditField
        NhapNEditFieldLabel          matlab.ui.control.Label
        CanduoiEditField             matlab.ui.control.NumericEditField
        CanduoiEditFieldLabel        matlab.ui.control.Label
        NhaphamsoEditField           matlab.ui.control.EditField
        NhaphamsoEditFieldLabel      matlab.ui.control.Label
        KieunhapDropDown             matlab.ui.control.DropDown
        KieunhapDropDownLabel        matlab.ui.control.Label
        LuachonDropDown              matlab.ui.control.DropDown
        LuachonDropDownLabel         matlab.ui.control.Label
        TICHPHANGANDUNGLabel         matlab.ui.control.Label
        PanelData                    matlab.ui.container.Panel
        NhapyEditField               matlab.ui.control.EditField
        NhapyEditFieldLabel          matlab.ui.control.Label
        NhapxEditField               matlab.ui.control.EditField
        NhapxEditFieldLabel          matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        KetquaButton                 matlab.ui.control.Button
        UIAxes_6                     matlab.ui.control.UIAxes
        GiithiunhmTab                matlab.ui.container.Tab
        huy489Label                  matlab.ui.control.Label
        dung21405Label               matlab.ui.control.Label
        hngngynt2005byteLabel        matlab.ui.control.Label
        zhunnggLabel                 matlab.ui.control.Label
        Dangkhoa280905Label          matlab.ui.control.Label
        MinWooUyLabel                matlab.ui.control.Label
        liemleeLabel                 matlab.ui.control.Label
        DTV_CLC3Label_5              matlab.ui.control.Label
        DTV_CLC3Label_4              matlab.ui.control.Label
        DTV_CLC3Label_3              matlab.ui.control.Label
        DTV_CLC2Label_2              matlab.ui.control.Label
        DTV_CLC3Label_2              matlab.ui.control.Label
        DTV_CLC3Label                matlab.ui.control.Label
        DTV_CLC2Label                matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        NguynThanhHuyLabel           matlab.ui.control.Label
        NguynNgcDngLabel             matlab.ui.control.Label
        NguynSinhHngLabel            matlab.ui.control.Label
        NguynVitHngLabel             matlab.ui.control.Label
        ngTriungKhoaLabel            matlab.ui.control.Label
        BiGiaHuyLabel                matlab.ui.control.Label
        LPhanThanhLimLabel           matlab.ui.control.Label
        UITable2                     matlab.ui.control.Table
        NTHCTHNHPHNGPHPTNHLabel      matlab.ui.control.Label
        UIAxes_3                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        flag = 0; % Description
        c = -2;
    end
    methods (Access = private)
        
        function y = TichPhanHinhThang(app, f, a, b, N)
        h = (b - a)/N;

        fa = f(a);
        fb = f(b);

        S = 0;
        for i = 1:N-1
            xi = a + i*h;
            S = S + f(xi);
        end

        y = h/2 * (fa + fb + 2*S);
        end  
    end
      
    
    methods (Access = private)
        
        function S = TichPhanHinhThangxy(app, x, y)
        N= (x(length(x))-x(1))/(x(2)-x(1));
        if length(x) ~= length(y)
        error('x và y phải có cùng số phần tử');
        end
        if N ~= length(x) - 1
            error('N phải bằng length(x) - 1');
        end
        lenx =length(x);
        h=(x(lenx)-x(1))/N; 

       
        S = y(1)+ y(lenx)+ 2*sum(y(2:(lenx-1)));
        S= h/2*S;
        end  
    end
   methods (Access = private)

    % ===== Simpson 1/3 cho ham so f(x) tren [a,b], N CHAN =====
    function I = TichPhanSimpson13(app, f, a, b, N)
        if mod(N,2) ~= 0
            error('Simpson 1/3: N phai chan');
        end
        h = (b-a)/N;
        x = a:h:b;
        y = f(x);
        I = h/3*(y(1)+y(end) + 4*sum(y(2:2:end-1)) + 2*sum(y(3:2:end-2)));
    end

    % ===== Simpson 3/8 cho ham so f(x) tren [a,b], N chia het cho 3 =====
    function I = TichPhanSimpson38(app, f, a, b, N)
        if mod(N,3) ~= 0
            error('Simpson 3/8: N phai chia het cho 3');
        end
        h = (b-a)/N;
        x = a:h:b;
        y = f(x);

        idx = 2:N;                       % cac chi so noi bo
        mult3 = idx(mod(idx,3)==0);       % chia het cho 3
        not3  = idx(mod(idx,3)~=0);       % khong chia het cho 3

        I = 3*h/8*( y(1) + y(end) + 2*sum(y(mult3)) + 3*sum(y(not3)) );
    end

    % ===== Simpson 1/3 cho du lieu (x,y) - x CACH DEU, N=length(x)-1 CHAN =====
    function I = TichPhanSimpson13xy(app, x, y)
        if length(x) ~= length(y)
            error('x va y phai co cung so phan tu');
        end
        x = x(:); y = y(:);
        N = length(x)-1;
        if mod(N,2) ~= 0
            error('Simpson 1/3 (xy): N=length(x)-1 phai chan');
        end

        h = x(2)-x(1);
        if any(abs(diff(x)-h) > 1e-12)
            error('Simpson (xy): x phai cach deu');
        end

        I = h/3*( y(1)+y(end) + 4*sum(y(2:2:end-1)) + 2*sum(y(3:2:end-2)) );
    end

    % ===== Simpson 3/8 cho du lieu (x,y) - x CACH DEU, N=length(x)-1 chia het cho 3 =====
    function I = TichPhanSimpson38xy(app, x, y)
        if length(x) ~= length(y)
            error('x va y phai co cung so phan tu');
        end
        x = x(:); y = y(:);
        N = length(x)-1;
        if mod(N,3) ~= 0
            error('Simpson 3/8 (xy): N=length(x)-1 phai chia het cho 3');
        end

        h = x(2)-x(1);
        if any(abs(diff(x)-h) > 1e-12)
            error('Simpson (xy): x phai cach deu');
        end

        idx = 2:N;
        mult3 = idx(mod(idx,3)==0);
        not3  = idx(mod(idx,3)~=0);

        I = 3*h/8*( y(1)+y(end) + 2*sum(y(mult3)) + 3*sum(y(not3)) );
    end
    
    function e = saisohinhthang_xy(app, x, y)
    % x, y: du lieu roi rac

    N = length(x) - 1;
    h = x(2) - x(1);

    % Xap xi f'' bang sai phan huu han
    f2 = diff(y, 2) / h^2;

    M2 = max(abs(f2));   % max |f''|

    e = (x(end) - x(1))^3 / (12 * N^2) * M2;
    end
    function e = saisohinhthang_f(app, f, a, b, N)
    % f : function handle @(x)
    % [a,b] : can tich phan
    % N : so doan

    x = linspace(a, b, 2000);
    y = f(x);

    h = x(2) - x(1);

    % Xap xi dao ham bac 2
    f2 = diff(y, 2) / h^2;

    M2 = max(abs(f2));   % max |f''|

    e = (b - a)^3 / (12 * N^2) * M2;
    end
    function e = saisoSimpson13_f(app, f, a, b, N)
    if mod(N,2) ~= 0
        error('Simpson 1/3: N phai chan');
    end

    % Lay mau day de xap xi dao ham bac 4
    x = linspace(a, b, 4000);
    y = f(x);

    h = x(2) - x(1);

    % Xap xi f'''' bang sai phan huu han
    f4 = diff(y, 4) / h^4;

    M4 = max(abs(f4));   % max |f''''|

    e = (b - a)^5 / (180 * N^4) * M4;
    end
    function e = saisoSimpson38_f(app, f, a, b, N)
    if mod(N,3) ~= 0
        error('Simpson 3/8: N phai chia het cho 3');
    end

    x = linspace(a, b, 4000);
    y = f(x);

    h = x(2) - x(1);

    f4 = diff(y, 4) / h^4;
    M4 = max(abs(f4));

    e = (b - a)^5 / (80 * N^4) * M4;
    end
    function e = saisoSimpson13_xy(app, x, y)
    N = length(x) - 1;

    if mod(N,2) ~= 0
        error('Simpson 1/3 (xy): N = length(x)-1 phai chan');
    end

    h = x(2) - x(1);

    % Xap xi dao ham bac 4
    f4 = diff(y, 4) / h^4;

    M4 = max(abs(f4));

    e = (x(end) - x(1))^5 / (180 * N^4) * M4;
    end
    function e = saisoSimpson38_xy(app, x, y)
    N = length(x) - 1;

    if mod(N,3) ~= 0
        error('Simpson 3/8 (xy): N =  phai chia het cho 3');
    end

    h = x(2) - x(1);

    f4 = diff(y, 4) / h^4;

    M4 = max(abs(f4));

    e = (x(end) - x(1))^5 / (80 * N^4) * M4;
end


end
    methods (Access = private)
        
        
        function [x,n] = chiadoi(app, fx, a, b, error)
            % Kiểm tra hàm số liên tục trong khoảng phân ly không
            if fx(a)*fx(b) >= 0
                uialert(app.UIFigure,['Hàm số không liên tục trong khoảng phân ly [a, b]. ' ...
                    'Vui lòng nhập lại [a, b] hoặc hàm số khác!'], 'lỗi');
                x = NaN; n = 0; return;
            end

            n = 1; % Số lần lặp
            max_in = 1000;
            while n < max_in
                x = (a + b)/2;

                if fx(x)*fx(b) > 0
                    b = x;
                else 
                    a = x;
                end

                if abs(b - a) < error
                return;
                end
            n = n + 1;
            end
            uialert(app.UIFigure, 'Đã hết số lần lặp tối đa mà chưa đạt sai số mong muốn.', 'Cảnh báo');
            end
        
        function [x, n] = lap(app, g, a, b, error)
            % Kiểm tra đạo hàm của g có q < 1 trong đoạn [a, b]
            syms x;
            g_sym = str2sym(g);
            dg_sym = diff(g_sym,x);
            dG = matlabFunction(dg_sym);disp(dG)
            if double(dG(a)) > 1 || double(dG(b)) > 1
                uialert(app.UIFigure, ['Đạo hàm g(x) = q > 1, không hội tụ! Vui lòng nhập lại hàm g(x) ' ...
                    'hoặc khoảng phân ly [a, b] khác'], 'Lỗi');
                x = NaN; n = 0; return;
            end
            
            g = str2func(['@(x)', g]);
            n = 1;
            %x0 = (a+b)/2;
            x0 = a;
            max_in = 1000;
            while n < max_in
                x = g(x0);

                % Kiểm tra sai số
                if abs(x - x0) < error
                    return;
                end
                
            n = n + 1;
            x0 = x;
            end
            x = x0; % Nếu vượt quá x bằng giá trị của số lần lặp tối đa
            uialert(app.UIFigure, 'Đã hết số lần lặp tối đa mà chưa đạt sai số mong muốn.', 'Cảnh báo');
        end
    
        
        function [x,n] = newton_raphson(app, fx, a, b, error)
            % Tính đạo hàm
            try
            syms x;
            f_sym = str2sym(fx);

            df_sym = diff(f_sym, x);
            ddf_sym = diff(df_sym, x);

            % Chuyển về hàm số
            F = matlabFunction(f_sym);%disp(F);
            dF = matlabFunction(df_sym);%disp(dF);
            ddF = matlabFunction(ddf_sym);%disp(ddF);
            catch
                uialert(app.UIFigure, 'Lỗi cú pháp phương trình!', 'Lỗi');
                x = NaN; n = 0; return;
            end
            
            % Xét điều kiện hội tụ
            if double(dF(a))*double(dF(b)) < 0
                uialert(app.UIFigure, 'Đạo hàm cấp 1 của hàm số đổi dấu trên khoảng [a, b]. Không hội tụ.', 'Không thỏa điều kiện');
                x = NaN; n = 0; return;
            elseif double(ddF(a))*double(ddF(b)) < 0
                uialert(app.UIFigure, 'Đạo hàm cấp 2 của hàm số đổi dấu trên khoảng [a, b]. Không hội tụ.', 'Không thỏa điều kiện');
                x = NaN; n = 0; return;
            end
            
            if double(F(a))*double(ddF(a)) > 0
                x0 = a;
            else 
                x0 = b;
            end

            %x0 = (a + b)/2;
            n = 0;
            max_in = 1000;
            while n < max_in
                n = n + 1;
                x1 = x0 - double(F(x0))/double(dF(x0));
                if abs(x1 - x0) < error
                    x = x1;
                return;
                end
                x0 = x1;
            end
        x = x1;  % Nếu vượt quá số lần lặp tối đa
        uialert(app.UIFigure, 'Đã hết số lần lặp tối đa mà chưa đạt sai số mong muốn.', 'Cảnh báo');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Các thông số khởi tạo ban đầu
            app.AEditField.Value = 0;
            app.BEditField.Value = 0.5;
            app.EndEditField.Value = 0.5;
            app.StartEditField.Value = -0.2;
            app.errorEditField.Value = 0.01;

            % Mặc định cho listbox
            app.ppListBox.Value = 'Chia đôi';
            app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)

            % QUAN TRỌNG: Kích hoạt nút RUN tự động
            % Gọi hàm callback của nút RUN như một hàm bình thường
            % Tham số thứ 2 là event, truyền [] (rỗng) vì không click chuột thật
            RUNButtonPushed(app, []);

            app.xEditField.Value = '1:9';
            app.yEditField.Value = '1, 1.5, 2, 3, 4, 5, 8, 10, 13';
        
            % Phương pháp mặc định
            app.PhuongPhapListBox.Value = 'Tuyen tinh';
        
            % Giá trị dự đoán mặc định
            app.NhapgiatridudoanEditField.Value = 6;
        
            % Xóa kết quả cũ
            app.KetquaphuongtrinhEditField.Value = '';
            app.KetquadudoanEditField.Value = 0;
        
            % Chuẩn bị trục
            cla(app.UIAxes)
            grid(app.UIAxes,'on')
            title(app.UIAxes,'Do thi hoi quy')
            xlabel(app.UIAxes,'X')
            ylabel(app.UIAxes,'Y')
        end

        % Callback function: EndEditField, RUNButton, StartEditField, 
        % ...and 1 other component
        function RUNButtonPushed(app, event)
            disp("--------- DEBUG: Nhấn nút được! ---------");
            % ----- Dữ liệu đầu vào -----
            endp = app.EndEditField.Value;
            startp = app.StartEditField.Value;
            func_str = app.inputEditField.Value;     

            % VD: x^2 -> x.^2
            draw_str = strrep(func_str, '^', '.^');
            draw_str = strrep(draw_str, '*', '.*');
            draw_str = strrep(draw_str, '/', './');
            func = str2func(['@(x)', draw_str]);
            disp(func);
            % ----- Thông số - tính toán vẽ đồ thị ------
            fs = 10000;
            t = linspace(startp, endp, fs);
            y = func(t);

            % ------ Vẽ đồ thị hàm số ------
            plot(app.UIAxes, real(t), real(y), 'b','LineWidth', 1.6);
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');

            % ------ Tìm nghiệm xấp xỉ ------
            a = app.AEditField.Value;
            b = app.BEditField.Value;
            error = app.errorEditField.Value;
            
            func_calc = str2func(['@(x)', func_str]);
            selectedOption = app.ppListBox.Value;
            try
                switch selectedOption
                    case 'Chia đôi'
                        [x, n] = app.chiadoi(func_calc, a, b, error);
                        yline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        xline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        % Vẽ điểm nghiệm
                        plot(app.UIAxes, x, func_calc(x), 'ro', 'MarkerFaceColor', 'r');
                        hold(app.UIAxes, 'off');
                    case 'Lặp'
                        [x, n] = app.lap(func_str, a, b, error);
                        yLine = @(k)(k);
                        plot(app.UIAxes, t, yLine(t), 'g-', 'LineWidth', 1.0);
                        xline(app.UIAxes, x, 'k--', 'LineWidth', 1.0);
                        % Vẽ điểm nghiệm
                        plot(app.UIAxes, real(x), 0, 'ro', 'MarkerFaceColor', 'r');
                        yline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        xline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        hold(app.UIAxes, 'off');
                    case 'Newton (tiếp tuyến)'
                        [x, n] = app.newton_raphson(func_str, a, b, error);
                        yline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        xline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        % Vẽ điểm nghiệm
                        plot(app.UIAxes, x, func_calc(x), 'ro', 'MarkerFaceColor', 'r');
                        hold(app.UIAxes, 'off');
                end
                
                % Hiển thị kết quả
                app.nEditField.Value = n;
                app.resultEditField.Value = double(x);
               
            catch ME
                %uialert(app.UIFigure, ME.message, 'Lỗi');%Debug
            end
            
        end

        % Value changed function: inputEditField
        function inputEditFieldValueChanged(app, event)
            currentStr = app.inputEditField.Value;
            if isempty(currentStr)
                app.flag = 0;

                currentMethod = app.ppListBox.Value;
                switch currentMethod
                    case 'Chia đôi'        
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                    case 'Lặp'
                        app.inputEditField.Value = '1 - sqrt(2)/2*exp(1/2*x)'; % g(x)
                    case 'Newton (tiếp tuyến)'
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                end
            else
                app.flag = 1;
            end
        end

        % Callback function
        function StartEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function EndEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: ppListBox
        function ppListBoxValueChanged(app, event)
            selectedOption = app.ppListBox.Value;

            if app.flag == 0
                switch selectedOption
                    case 'Chia đôi'        
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                    case 'Lặp'
                        app.inputEditField.Value = '1 - sqrt(2)/2*exp(1/2*x)'; % g(x)
                    case 'Newton (tiếp tuyến)'
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                end
            else 
                return;
            end
        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            app.c = -2;
            app.StartEditField.Value = app.AEditField.Value + app.c;
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            if app.BEditField.Value > 0
                app.c = 2;
            end
            app.EndEditField.Value = app.BEditField.Value + app.c;
        end

        % Callback function
        function errorEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: resultEditField
        function resultEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function RUNButtonPushed2(app, event)
            
        end

        % Callback function
        function RunButtonPushed(app, event)

        end

        % Button pushed function: RunButton
        function RunButtonPushed2(app, event)
    %% ===== 1. LẤY DỮ LIỆU =====
    x = str2num(app.xEditField.Value); %#ok<ST2NM>
    y = str2num(app.yEditField.Value); %#ok<ST2NM>
    
    if isempty(x) || isempty(y)
        uialert(app.UIFigure,'Chưa nhập x hoặc y','Lỗi');
        return
    end
    if length(x) ~= length(y)
        uialert(app.UIFigure,'x và y phải cùng số phần tử','Lỗi');
        return
    end

    %% ===== 2. CHỌN PHƯƠNG PHÁP & GỌI HÀM =====
    method = app.PhuongPhapListBox.Value;
    
    % Khởi tạo các biến để tránh lỗi Scope
    a_val = 0; b_val = 0; 
    
    try
        switch method
            case 'Tuyen tinh'
                [a1, a0, y_fit, eqn] = regressionLinear(x, y);
                a_val = a1; b_val = a0; % Lưu để dùng cho dự đoán
            case 'Ham luy thua'
                [a, b, y_fit, eqn] = regressionPower(x, y);
                a_val = a; b_val = b;
            case 'Ham mu'
                [a, b, y_fit, eqn] = regressionExp(x, y);
                a_val = a; b_val = b;
        end
    catch ME
        uialert(app.UIFigure, ME.message, 'Lỗi');
        return
    end

    %% ===== 3. HIỂN THỊ PHƯƠNG TRÌNH =====
    app.KetquaphuongtrinhEditField.Value = eqn;

    %% ===== 4. VẼ ĐỒ THỊ =====
    cla(app.UIAxes_4)
    hold(app.UIAxes_4,'on')
    plot(app.UIAxes_4, x, y, 'bo', 'MarkerFaceColor','b', 'DisplayName', 'Dữ liệu')
    
    % Tạo đường vẽ mịn
    xx = linspace(min(x), max(x), 200);
    switch method
        case 'Tuyen tinh'
            yy = a_val * xx + b_val;
        case 'Ham luy thua'
            yy = a_val * (xx .^ b_val);
        case 'Ham mu'
            yy = a_val * exp(b_val * xx);
    end
    
    plot(app.UIAxes_4, xx, yy, 'r', 'LineWidth', 2, 'DisplayName', 'Hồi quy')
    grid(app.UIAxes_4,'on')
    legend(app.UIAxes_4, 'show', 'Location','best')
    hold(app.UIAxes_4,'off')

    %% ===== 5. DỰ ĐOÁN =====
    x0 = app.NhapgiatridudoanEditField.Value;
    y0 = 0;
    
    switch method
        case 'Tuyen tinh'
            y0 = a_val * x0 + b_val;
        case 'Ham luy thua'
            if x0 <= 0
                uialert(app.UIFigure,'Với hàm lũy thừa, x0 phải > 0','Lỗi');
                return
            end
            y0 = a_val * (x0 ^ b_val);
        case 'Ham mu'
            y0 = a_val * exp(b_val * x0);
    end
    app.KetquadudoanEditField.Value = y0;

    %% ===== 6. VẼ ĐIỂM DỰ ĐOÁN =====
    hold(app.UIAxes_4,'on')
    plot(app.UIAxes_4, x0, y0, 'gs', 'MarkerSize', 10, 'LineWidth', 2, 'DisplayName', 'Dự đoán')
    hold(app.UIAxes_4,'off')
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            % Khởi tạo các biến kết quả
            poly_str = ''; 
            interp_value = 0;
            
            try
                % 1. Đọc và Kiểm tra Dữ liệu
                x = str2num(app.DLX.Value); 
                y = str2num(app.DLY.Value); 
                
                if isempty(x) || isempty(y)
                    uialert(app.UIFigure,'Chưa nhập dữ liệu x hoặc y','Lỗi Dữ liệu'); return;
                end
                n = length(x);
                if n ~= length(y)
                    uialert(app.UIFigure,'x và y phải cùng số phần tử','Lỗi Dữ liệu'); return;
                end
                if n < 2
                    uialert(app.UIFigure,'Phải có ít nhất 2 điểm dữ liệu','Lỗi Dữ liệu'); return;
                end
                
                % Kiểm tra trùng lặp x
                if length(unique(x)) ~= n
                     uialert(app.UIFigure,'Các giá trị x không được trùng nhau!','Lỗi Tính toán'); return;
                end
        
                method = app.DropDown.Value; 
                x_interp = app.GTCNS.Value;
                
                %% ===== 2. TÍNH TOÁN GIÁ TRỊ NỘI SUY (Tại điểm x cần tìm) =====
                % Dùng hàm polyfit để tìm hệ số đa thức: p(1)*x^N + p(2)*x^(N-1) + ... + p(N+1)
                % Đa thức nội suy đi qua n điểm sẽ có bậc là n-1
                p_coeffs = polyfit(x, y, n-1);
                
                % Tính giá trị tại điểm x_interp bằng hàm polyval
                interp_value = polyval(p_coeffs, x_interp);
                
                %% ===== 3. TẠO CHUỖI ĐA THỨC HIỂN THỊ (P(x) = ...) =====
                % Phần này chuyển đổi mảng hệ số p_coeffs thành chuỗi đẹp mắt
                poly_str = 'P(x) = ';
                degree = length(p_coeffs) - 1;
                
                for i = 1:length(p_coeffs)
                    coef = p_coeffs(i);
                    power = degree - (i-1);
                    
                    % Chỉ hiển thị các hệ số đáng kể (khác 0)
                    if abs(coef) > 1e-5 
                        
                        % Xử lý dấu cộng/trừ
                        if i > 1 
                            if coef >= 0
                                poly_str = [poly_str ' + ']; %#ok<*AGROW>
                            else
                                poly_str = [poly_str ' - '];
                            end
                        elseif coef < 0 % Số đầu tiên âm
                            poly_str = [poly_str '-'];
                        end
                        
                        % Xử lý giá trị số (Lấy trị tuyệt đối)
                        poly_str = [poly_str num2str(abs(coef), '%.4g')];
                        
                        % Xử lý biến x và số mũ
                        if power == 1
                            poly_str = [poly_str '*x'];
                        elseif power > 1
                            poly_str = [poly_str '*x^' num2str(power)];
                        end
                    end
                end
                
                % Trường hợp đa thức bằng 0 hoặc rỗng
                if strcmp(poly_str, 'P(x) = ')
                    poly_str = 'P(x) = 0';
                end
        
                %% ===== 4. HIỂN THỊ KẾT QUẢ =====
                app.KQNS.Value = interp_value; 
                app.KQDTNS.Value = poly_str;       
                
                %% ===== 5. VẼ ĐỒ THỊ =====
                cla(app.UIAxes_5);
                hold(app.UIAxes_5,'on');
                
                % Tạo dữ liệu vẽ đường cong mịn
                x_plot = linspace(min(x), max(x), 100);
                y_plot = polyval(p_coeffs, x_plot);
                
                % Vẽ đường nội suy
                plot(app.UIAxes_5, x_plot, y_plot, 'r-', 'LineWidth', 2, 'DisplayName', ['Nội suy ' method]);
                
                % Vẽ các điểm nút (Dữ liệu gốc)
                plot(app.UIAxes_5, x, y, 'b.', 'MarkerSize', 20, 'DisplayName', 'Điểm nút');
                
                % Vẽ điểm kết quả nội suy
                plot(app.UIAxes_5, x_interp, interp_value, 'g*', 'MarkerSize', 10, 'LineWidth', 2, 'DisplayName', 'Kết quả');
                
                hold(app.UIAxes_5,'off');
                title(app.UIAxes_5, ['Đồ thị nội suy ' method]);
                xlabel(app.UIAxes_5, 'X');
                ylabel(app.UIAxes_5, 'Y');
                legend(app.UIAxes_5, 'Location', 'best');
                grid(app.UIAxes_5, 'on');
                
            catch ME
                uialert(app.UIFigure, ['Đã xảy ra lỗi: ', ME.message], 'Lỗi Tính toán');
                app.KQNS.Value = 0; 
                app.KQDTNS.Value = 'Lỗi';
            
            end
        end

        % Button down function: UIAxes_5
        function UIAxes_5ButtonDown(app, event)
            
        end

        % Button pushed function: KetquaButton
        function Ketqua(app, event)
             %Lay gia tri
            x_str     = app.NhapxEditField.Value;
            y_str     = app.NhapyEditField.Value;
            hamso_str = app.NhaphamsoEditField.Value;
            option    = app.LuachonDropDown.Value;

            a = app.CanduoiEditField.Value;
            b = app.CantrenEditField.Value;
            N = app.NhapNEditField.Value;
            %Xu li
            
            x = str2num(x_str);
            y = str2num(y_str);


    switch option
    case 'Hinh thang'
        if ~isempty(x) && ~isempty(y)
            S = app.TichPhanHinhThangxy(x, y);
            xplot = x; yplot = y;
        else
            fx = str2func("@(x)" + hamso_str);
            S  = app.TichPhanHinhThang(fx, a, b, N);
            xplot = linspace(a, b, N+1);
            yplot = fx(xplot);
        end
%start LIM 
    case 'Simpson1/3'
        if ~isempty(x) && ~isempty(y)
            % Simpson 1/3 voi du lieu: N = length(x)-1 phai chan
            try
                S = app.TichPhanSimpson13xy(x, y);
            catch ME
                uialert(app.UIFigure, ME.message, 'Loi Simpson 1/3 (xy)');
                return;
            end
            xplot = x; yplot = y;
        else
            if mod(N,2) ~= 0
                uialert(app.UIFigure,'Simpson 1/3 can N chan','Sai N');
                return;
            end
            fx = str2func("@(x)" + hamso_str);
            try
                S = app.TichPhanSimpson13(fx, a, b, N);
            catch ME
                uialert(app.UIFigure, ME.message, 'Loi Simpson 1/3');
                return;
            end
            xplot = linspace(a, b, N+1);
            yplot = fx(xplot);
        end

    case 'Simpson3/8'
        if ~isempty(x) && ~isempty(y)
            % Simpson 3/8 voi du lieu: N = length(x)-1 chia het cho 3
            try
                S = app.TichPhanSimpson38xy(x, y);
            catch ME
                uialert(app.UIFigure, ME.message, 'Loi Simpson 3/8 (xy)');
                return;
            end
            xplot = x; yplot = y;
        else
            if mod(N,3) ~= 0
                uialert(app.UIFigure,'Simpson 3/8 can N chia het cho 3','Sai N');
                return;
            end
            fx = str2func("@(x)" + hamso_str);
            try
                S = app.TichPhanSimpson38(fx, a, b, N);
            catch ME
                uialert(app.UIFigure, ME.message, 'Loi Simpson 3/8');
                return;
            end
            xplot = linspace(a, b, N+1);
            yplot = fx(xplot);
        end
        
        
    end
  %end LIM
        switch option
    case 'Hinh thang'
        if ~isempty(x) && ~isempty(y)
            % Sai so voi du lieu roi rac
            e = app.saisohinhthang_xy(x, y);
        else
            % Sai so theo ham
            fx = str2func("@(x)" + hamso_str);
            e  = app.saisohinhthang_f(fx, a, b, N);
        end

    case 'Simpson1/3'
        if ~isempty(x) && ~isempty(y)
            % Sai so Simpson 1/3 (xy)
            e = app.saisoSimpson13_xy(x, y);
        else
            % Sai so Simpson 1/3 (ham)
            fx = str2func("@(x)" + hamso_str);
            e  = app.saisoSimpson13_f(fx, a, b, N);
        end

    case 'Simpson3/8'
        if ~isempty(x) && ~isempty(y)
            % Sai so Simpson 3/8 (xy)
            e = app.saisoSimpson38_xy(x, y);
        else
            % Sai so Simpson 3/8 (ham)
            fx = str2func("@(x)" + hamso_str);
            e  = app.saisoSimpson38_f(fx, a, b, N);
        end
    end


        cla(app.UIAxes_6);
        hold(app.UIAxes_6, 'on');

        plot(app.UIAxes_6, xplot, yplot, 'LineWidth', 2);
        area(app.UIAxes_6, xplot, yplot, 'FaceAlpha', 0.3, 'EdgeColor', 'none');

        % Ve truc Ox theo dung [a,b]
        plot(app.UIAxes_6, [a b], [0 0], 'k', 'LineWidth', 1);

        grid(app.UIAxes_6, 'on');
        hold(app.UIAxes_6, 'off');

        app.Label.Text = sprintf('Gia tri tich phan gan dung = %.6f   |   Sai so ≈ %.2e', S, e);
        end

        % Value changed function: LuachonDropDown
        function ValueChangedFcn(app, event)
            value = app.LuachonDropDown.Value;
            
        end

        % Value changed function: KieunhapDropDown
        function Kieunhap(app, event)
            value = app.KieunhapDropDown.Value;

    if strcmp(value, 'Roi rac')
        app.NhapxEditField.Value  = '[1 2 3 4 5]';
        app.PanelData.Visible     = 'on';
        app.PanelFunction.Visible = 'off';
    else
        app.NhapxEditField.Value ='';
        app.PanelData.Visible     = 'off';
        app.PanelFunction.Visible = 'on';
    end
            
        end

        % Value changed function: NhapxEditField
        function Nhapx(app, event)
            value = app.NhapxEditField.Value;
            
        end

        % Value changed function: NhapyEditField
        function Nhapy(app, event)
            value = app.NhapyEditField.Value;
            
        end

        % Value changed function: NhaphamsoEditField
        function Nhaphamso(app, event)
            value = app.NhaphamsoEditField.Value;
            
        end

        % Value changed function: CanduoiEditField
        function Canduoi(app, event)
            value = app.CanduoiEditField.Value;
            
        end

        % Value changed function: CantrenEditField
        function Cantren(app, event)
            value = app.CantrenEditField.Value;
            
        end

        % Value changed function: NhapNEditField
        function NhapN(app, event)
            value = app.NhapNEditField.Value;
            
        end

        % Button pushed function: ButtonCalc
        function ButtonCalcPushed(app, event)
            app.StatusLamp.Color = [1.00 0.84 0.00]; % Màu vàng (Gold)
    drawnow; % Lệnh bắt buộc để cập nhật giao diện ngay lập tức
    
    mode = app.DropDownInputMode.Value;
    method = app.DropDownMethod.Value;
    order = app.DropDownOrder.Value;
    result = 0;
    
    try
        % ---------------------------------------------------------
        % NHÁNH 1: TÍNH TOÁN DỰA TRÊN HÀM SỐ f(x)
        % ---------------------------------------------------------
        if strcmp(mode, 'Hàm số f(x)')
            % Lấy dữ liệu đầu vào
            strFunc = app.EditFieldFunc.Value;
            x0 = app.EditFieldX_Func.Value;
            h = app.EditFieldH.Value;
            
            % Chuyển chuỗi thành hàm số (Anonymous Function)
            % Tham khảo cách dùng str2func trong giáo trình trang 62 [cite: 1704]
            try
                f = str2func(['@(x) ' strFunc]); 
            catch
                error('Cú pháp hàm số không hợp lệ. Ví dụ đúng: sin(x), x^2 + 1');
            end
            
            % Áp dụng công thức tính đạo hàm
            if contains(method, 'Tiến') % Forward
                if strcmp(order, 'O(h)')
                    % Công thức xấp xỉ tiến O(h) [cite: 1007]
                    result = (f(x0 + h) - f(x0)) / h;
                else
                    % Công thức xấp xỉ tiến O(h^2) [cite: 1011]
                    result = (-3*f(x0) + 4*f(x0 + h) - f(x0 + 2*h)) / (2*h);
                end
                
            elseif contains(method, 'Lùi') % Backward
                if strcmp(order, 'O(h)')
                    % Công thức xấp xỉ lùi O(h) [cite: 1010]
                    result = (f(x0) - f(x0 - h)) / h;
                else
                    % Công thức xấp xỉ lùi O(h^2) [cite: 1014]
                    result = (f(x0 - 2*h) - 4*f(x0 - h) + 3*f(x0)) / (2*h);
                end
                
            else % Trung tâm (Central)
                 % Công thức xấp xỉ trung tâm (thường mặc định O(h^2)) [cite: 1001]
                 result = (f(x0 + h) - f(x0 - h)) / (2*h);
            end
            
            % Chuẩn bị dữ liệu để vẽ đồ thị
            x_plot = linspace(x0 - 5*h, x0 + 5*h, 100);
            y_plot = f(x_plot);
            y0 = f(x0);
            
        % ---------------------------------------------------------
        % NHÁNH 2: TÍNH TOÁN DỰA TRÊN DỮ LIỆU MẢNG (RỜI RẠC)
        % ---------------------------------------------------------
        else
            % Chuyển chuỗi nhập vào thành mảng số
            arrX = str2num(app.EditFieldArrX.Value); %#ok<ST2NM>
            arrY = str2num(app.EditFieldArrY.Value); %#ok<ST2NM>
            x0 = app.EditFieldX_Data.Value;
            
            % Kiểm tra tính hợp lệ của dữ liệu
            if isempty(arrX) || isempty(arrY)
                error('Dữ liệu mảng X hoặc Y đang trống hoặc sai định dạng.');
            end
            if length(arrX) ~= length(arrY)
                error('Độ dài mảng X và mảng Y phải bằng nhau.');
            end
            
            % Tìm vị trí (index) của x0 trong mảng X
            idx = find(arrX == x0, 1);
            if isempty(idx)
                error('Giá trị x cần tính không tồn tại trong mảng dữ liệu X.');
            end
            
            % Tự động tính bước h dựa trên các phần tử lân cận
            if idx < length(arrX)
                h = arrX(idx+1) - arrX(idx);
            elseif idx > 1
                h = arrX(idx) - arrX(idx-1);
            else
                h = 1; % Giá trị mặc định nếu mảng chỉ có 1 phần tử (tránh chia cho 0)
            end 
            
            y_curr = arrY(idx);
            
            % Tính toán dựa trên Index mảng
            if contains(method, 'Tiến')
                if idx >= length(arrX)
                     error('Không đủ điểm dữ liệu phía sau để tính Xấp xỉ Tiến.');
                end
                % Công thức rời rạc O(h): (y_{i+1} - y_i) / h
                result = (arrY(idx+1) - y_curr) / h;
                
            elseif contains(method, 'Lùi')
                if idx <= 1
                     error('Không đủ điểm dữ liệu phía trước để tính Xấp xỉ Lùi.');
                end
                % Công thức rời rạc O(h): (y_i - y_{i-1}) / h
                result = (y_curr - arrY(idx-1)) / h;
                
            else % Trung tâm
                if idx <= 1 || idx >= length(arrX)
                    error('Cần điểm dữ liệu ở cả hai phía để tính Xấp xỉ Trung tâm.');
                end
                % Công thức rời rạc O(h^2): (y_{i+1} - y_{i-1}) / (2h)
                result = (arrY(idx+1) - arrY(idx-1)) / (2*h);
            end
            
            % Chuẩn bị dữ liệu để vẽ
            x_plot = arrX;
            y_plot = arrY;
            y0 = y_curr;
        end
        
        % ---------------------------------------------------------
        % 3. HIỂN THỊ KẾT QUẢ VÀ VẼ ĐỒ THỊ
        % ---------------------------------------------------------
        
        % Cập nhật kết quả vào ô vuông (Numeric Edit Field)
        app.EditFieldResult.Value = result;
        
        % Xử lý đồ thị trên UIAxes
        cla(app.UIAxes_7);         % Xóa đồ thị cũ
        hold(app.UIAxes_7, 'on');  % Giữ lại các nét vẽ chồng lên nhau
        
        % Vẽ đường hàm số hoặc các điểm dữ liệu
        if strcmp(mode, 'Hàm số f(x)')
            plot(app.UIAxes_7, x_plot, y_plot, 'b-', 'LineWidth', 1.5, 'DisplayName', 'Hàm f(x)');
        else
            plot(app.UIAxes_7, x_plot, y_plot, 'bo--', 'LineWidth', 1, 'DisplayName', 'Dữ liệu mảng');
        end
        
        % Vẽ điểm đang tính toán (màu đỏ đậm)
        plot(app.UIAxes_7, x0, y0, 'r*', 'MarkerSize', 10, 'LineWidth', 2, 'DisplayName', 'Điểm tính đạo hàm');
        
        % Vẽ đường tiếp tuyến: y = f'(x0)*(x - x0) + f(x0)
        % Tạo khoảng vẽ ngắn xung quanh x0 để minh họa độ dốc
        x_tangent = linspace(x0 - h, x0 + h, 50); 
        y_tangent = result * (x_tangent - x0) + y0;
        plot(app.UIAxes_7, x_tangent, y_tangent, 'r-', 'LineWidth', 2, 'DisplayName', 'Tiếp tuyến (Gần đúng)');
        
        % Trang trí đồ thị
        title(app.UIAxes_7, ['Minh họa đạo hàm tại x = ' num2str(x0)]);
        xlabel(app.UIAxes_7, 'x');
        ylabel(app.UIAxes_7, 'f(x)');
        grid(app.UIAxes_7, 'on');
        legend(app.UIAxes_7);
        hold(app.UIAxes_7, 'off');
        
        app.StatusLamp.Color = [0.00 1.00 0.00];
        
    catch ME
        app.StatusLamp.Color = [1.00 0.00 0.00];
        % Hiển thị thông báo lỗi chuyên nghiệp bằng uialert
        uialert(app.UIFigure, ME.message, 'Lỗi Tính Toán', 'Icon', 'error');
        % Reset ô kết quả về 0 hoặc rỗng nếu muốn
        app.EditFieldResult.Value = 0; 
    end
        end

        % Value changed function: DropDownInputMode
        function DropDownInputModeValueChanged(app, event)
            value = app.DropDownInputMode.Value;
            if strcmp(value, 'Hàm số f(x)')
        app.PanelFunction_2.Visible = 'on'; % Hiện khung nhập hàm
        app.PanelData_2.Visible = 'off';    % Ẩn khung nhập mảng
    else
        app.PanelFunction_2.Visible = 'off'; % Ẩn khung nhập hàm
        app.PanelData_2.Visible = 'on';      % Hiện khung nhập mảng
    end
        end

        % Size changed function: InputPanel
        function InputPanelSizeChanged(app, event)
            position = app.InputPanel.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Do thi hoi quy')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghiemTab);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'f(x)')
            zlabel(app.UIAxes, 'Z')
            app.RUNButton = uibutton(app.NghiemTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.inputEditField = uieditfield(app.NghiemTab, 'text');
            app.inputEditField.ValueChangedFcn = createCallbackFcn(app, @inputEditFieldValueChanged, true);
            app.AEditFieldLabel = uilabel(app.NghiemTab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.NghiemTab, 'numeric');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.BEditFieldLabel = uilabel(app.NghiemTab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.NghiemTab, 'numeric');
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.NhpkhongphnlynghimABLabel = uilabel(app.NghiemTab);
            app.NhpkhongphnlynghimABLabel.Text = 'Nhập khoảng phân ly nghiệm [A, B]';
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.errorEditField = uieditfield(app.NghiemTab, 'numeric');
            app.errorEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.PhngphpListBoxLabel = uilabel(app.NghiemTab);
            app.PhngphpListBoxLabel.Text = 'Phương pháp';
            app.ppListBox = uilistbox(app.NghiemTab);
            app.ppListBox.Items = {'Chia đôi', 'Lặp', 'Newton (tiếp tuyến)'};
            app.ppListBox.ValueChangedFcn = createCallbackFcn(app, @ppListBoxValueChanged, true);
            app.ppListBox.Value = 'Chia đôi';
            app.KtquEditFieldLabel = uilabel(app.NghiemTab);
            app.KtquEditFieldLabel.Text = 'Kêt quả';
            app.resultEditField = uieditfield(app.NghiemTab, 'numeric');
            app.resultEditField.ValueChangedFcn = createCallbackFcn(app, @resultEditFieldValueChanged, true);
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.nEditField = uieditfield(app.NghiemTab, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.StartEditFieldLabel = uilabel(app.NghiemTab);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.NghiemTab, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.EndEditFieldLabel = uilabel(app.NghiemTab);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.NghiemTab, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes_5 = uiaxes(app.NisuyTab);
            title(app.UIAxes_5, 'Đồ thị hàm số nội suy')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.ButtonDownFcn = createCallbackFcn(app, @UIAxes_5ButtonDown, true);
            app.DropDown = uidropdown(app.NisuyTab);
            app.DropDown.Items = {'NewTon', 'Larange'};
            app.DropDown.Value = 'NewTon';
            app.GTCNS = uieditfield(app.NisuyTab, 'numeric');
            app.GTCNS.Value = 0.14;
            app.RunButton_2 = uibutton(app.NisuyTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.KQDTNS = uieditfield(app.NisuyTab, 'text');
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KQNS = uieditfield(app.NisuyTab, 'numeric');
            app.NhpdliuxLabel = uilabel(app.NisuyTab);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x';
            app.DLX = uieditfield(app.NisuyTab, 'text');
            app.DLX.Value = '0.1 0.2 0.3  0.4';
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.DLY = uieditfield(app.NisuyTab, 'text');
            app.athcnisuyLabel = uilabel(app.NisuyTab);
            app.athcnisuyLabel.Text = 'Đa thức nội suy';
            app.GitrnisuyLabel = uilabel(app.NisuyTab);
            app.GitrnisuyLabel.Text = 'Giá trị nội suy';
            app.PhngphpLabel = uilabel(app.NisuyTab);
            app.PhngphpLabel.Text = 'Phương pháp';
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes_4 = uiaxes(app.HiquyTab);
            title(app.UIAxes_4, 'Do thi hoi quy')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.RunButton = uibutton(app.HiquyTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed2, true);
            app.RunButton.Text = 'Run';
            app.NhapgiatridudoanEditField = uieditfield(app.HiquyTab, 'numeric');
            app.KetquadudoanEditField = uieditfield(app.HiquyTab, 'numeric');
            app.xEditField_2Label = uilabel(app.HiquyTab);
            app.xEditField_2Label.Text = 'x';
            app.xEditField = uieditfield(app.HiquyTab, 'text');
            app.xEditField.Value = '1';
            app.yEditField_2Label = uilabel(app.HiquyTab);
            app.yEditField_2Label.Text = 'y';
            app.yEditField = uieditfield(app.HiquyTab, 'text');
            app.PhuongPhapListBox_2Label = uilabel(app.HiquyTab);
            app.PhuongPhapListBox_2Label.Text = 'Phuong Phap';
            app.PhuongPhapListBox = uilistbox(app.HiquyTab);
            app.PhuongPhapListBox.Items = {'Tuyen tinh', 'Ham luy thua', 'Ham mu'};
            app.PhuongPhapListBox.Value = 'Ham mu';
            app.KetquaphuongtrinhEditField_2Label = uilabel(app.HiquyTab);
            app.KetquaphuongtrinhEditField_2Label.Text = 'Ket qua phuong trinh';
            app.KetquaphuongtrinhEditField = uieditfield(app.HiquyTab, 'text');
            app.DonktquLabel = uilabel(app.HiquyTab);
            app.DonktquLabel.Text = 'Dự đoán kết quả:';
            app.xLabel = uilabel(app.HiquyTab);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.HiquyTab);
            app.yLabel.Text = 'y';
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.TabGroup_2 = uitabgroup(app.ohmTab);
            app.ohmTab_2 = uitab(app.TabGroup_2);
            app.ohmTab_2.Title = 'Đạo hàm';
            app.UIAxes_7 = uiaxes(app.ohmTab_2);
            title(app.UIAxes_7, 'Đồ thị hàm số và tiếp tuyến')
            xlabel(app.UIAxes_7, 'Giá trị của x')
            ylabel(app.UIAxes_7, 'Giá trị hàm số f(x)')
            zlabel(app.UIAxes_7, 'Z')
            app.InputPanel = uipanel(app.ohmTab_2);
            app.InputPanel.Title = 'Input';
            app.InputPanel.SizeChangedFcn = createCallbackFcn(app, @InputPanelSizeChanged, true);
            app.ChnchnhpDropDownLabel = uilabel(app.InputPanel);
            app.ChnchnhpDropDownLabel.Text = 'Chọn chế độ nhập:';
            app.DropDownInputMode = uidropdown(app.InputPanel);
            app.DropDownInputMode.Items = {'Hàm số f(x)', 'Dữ liệu mảng (x, y)'};
            app.DropDownInputMode.ValueChangedFcn = createCallbackFcn(app, @DropDownInputModeValueChanged, true);
            app.DropDownInputMode.Value = 'Hàm số f(x)';
            app.PanelFunction_2 = uipanel(app.ohmTab_2);
            app.PanelFunction_2.Title = 'Nhập các giá trị sau:';
            app.TiimxLabel = uilabel(app.PanelFunction_2);
            app.TiimxLabel.Text = 'Tại điểm x =';
            app.EditFieldX_Func = uieditfield(app.PanelFunction_2, 'numeric');
            app.EditFieldX_Func.Value = 3;
            app.HmfxLabel = uilabel(app.PanelFunction_2);
            app.HmfxLabel.Text = 'Hàm f(x):';
            app.EditFieldFunc = uieditfield(app.PanelFunction_2, 'text');
            app.EditFieldFunc.Value = 'sin(x)';
            app.BcnhyhLabel = uilabel(app.PanelFunction_2);
            app.BcnhyhLabel.Text = 'Bước nhảy h =';
            app.EditFieldH = uieditfield(app.PanelFunction_2, 'numeric');
            app.EditFieldH.Value = 0.1;
            app.PanelData_2 = uipanel(app.PanelFunction_2);
            app.PanelData_2.Title = 'Nhập các giá trị sau:';
            app.PanelData_2.Visible = 'off';
            app.MngytngngLabel = uilabel(app.PanelData_2);
            app.MngytngngLabel.Text = 'Mảng y:';
            app.EditFieldArrY = uieditfield(app.PanelData_2, 'text');
            app.EditFieldArrY.Value = '6 7 8 9';
            app.TiimxLabel_2 = uilabel(app.PanelData_2);
            app.TiimxLabel_2.Text = 'Tại điểm x =';
            app.EditFieldX_Data = uieditfield(app.PanelData_2, 'numeric');
            app.EditFieldX_Data.Value = 3;
            app.MngxcchnhaubiducchLabel = uilabel(app.PanelData_2);
            app.MngxcchnhaubiducchLabel.Text = 'Mảng x:';
            app.EditFieldArrX = uieditfield(app.PanelData_2, 'text');
            app.EditFieldArrX.Value = '1 2 3 4';
            app.ChnchcnngPanel = uipanel(app.ohmTab_2);
            app.ChnchcnngPanel.Title = 'Chọn chức năng';
            app.PhngphpLabel_2 = uilabel(app.ChnchcnngPanel);
            app.PhngphpLabel_2.Text = 'Phương pháp:';
            app.DropDownMethod = uidropdown(app.ChnchcnngPanel);
            app.DropDownMethod.Items = {'Tiến', 'Lùi', 'Trung tâm'};
            app.DropDownMethod.Value = 'Tiến';
            app.SaisLabel = uilabel(app.ChnchcnngPanel);
            app.SaisLabel.Text = 'Sai số:';
            app.DropDownOrder = uidropdown(app.ChnchcnngPanel);
            app.DropDownOrder.Items = {'O(h)', 'O(h^2)'};
            app.DropDownOrder.Value = 'O(h)';
            app.ButtonCalc = uibutton(app.ohmTab_2, 'push');
            app.ButtonCalc.ButtonPushedFcn = createCallbackFcn(app, @ButtonCalcPushed, true);
            app.ButtonCalc.Text = 'TÍNH ĐẠO HÀM';
            app.Gia_tri_dao_ham = uilabel(app.ohmTab_2);
            app.Gia_tri_dao_ham.Text = 'Giá trị đạo hàm (y''):';
            app.EditFieldResult = uieditfield(app.ohmTab_2, 'numeric');
            app.EditFieldResult.ValueDisplayFormat = '%.4f';
            app.EditFieldResult.Editable = 'off';
            app.Image = uiimage(app.ohmTab_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo-hcmus-new.jpg');
            app.TrngthiLabel = uilabel(app.ohmTab_2);
            app.TrngthiLabel.Text = 'Trạng thái';
            app.StatusLamp = uilamp(app.ohmTab_2);
            app.NTHCHNHPHNGPHPTNHLabel = uilabel(app.ohmTab_2);
            app.NTHCHNHPHNGPHPTNHLabel.Text = {'ĐỒ ÁN THỰC HÀNH'; 'PHƯƠNG PHÁP TÍNH'};
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.UIAxes_6 = uiaxes(app.TchphnTab);
            title(app.UIAxes_6, 'Phan dien tich ')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.KetquaButton = uibutton(app.TchphnTab, 'push');
            app.KetquaButton.ButtonPushedFcn = createCallbackFcn(app, @Ketqua, true);
            app.KetquaButton.Text = 'Ket qua';
            app.Label = uilabel(app.TchphnTab);
            app.Label.Text = '  ';
            app.PanelData = uipanel(app.TchphnTab);
            app.PanelData.Title = 'PanelData';
            app.NhapxEditFieldLabel = uilabel(app.PanelData);
            app.NhapxEditFieldLabel.Text = 'Nhap x: ';
            app.NhapxEditField = uieditfield(app.PanelData, 'text');
            app.NhapxEditField.ValueChangedFcn = createCallbackFcn(app, @Nhapx, true);
            app.NhapxEditField.Value = '[1 2 3 4 5]';
            app.NhapyEditFieldLabel = uilabel(app.PanelData);
            app.NhapyEditFieldLabel.Text = 'Nhap y: ';
            app.NhapyEditField = uieditfield(app.PanelData, 'text');
            app.NhapyEditField.ValueChangedFcn = createCallbackFcn(app, @Nhapy, true);
            app.NhapyEditField.Value = '[1 1/2 1/3 1/4 1/5]';
            app.TICHPHANGANDUNGLabel = uilabel(app.TchphnTab);
            app.TICHPHANGANDUNGLabel.Text = 'TICH PHAN GAN DUNG';
            app.LuachonDropDownLabel = uilabel(app.TchphnTab);
            app.LuachonDropDownLabel.Text = 'Lua chon:';
            app.LuachonDropDown = uidropdown(app.TchphnTab);
            app.LuachonDropDown.Items = {'Hinh thang', 'Simpson1/3', 'Simpson3/8'};
            app.LuachonDropDown.ValueChangedFcn = createCallbackFcn(app, @ValueChangedFcn, true);
            app.LuachonDropDown.Value = 'Hinh thang';
            app.KieunhapDropDownLabel = uilabel(app.TchphnTab);
            app.KieunhapDropDownLabel.Text = 'Kieu nhap : ';
            app.KieunhapDropDown = uidropdown(app.TchphnTab);
            app.KieunhapDropDown.Items = {'Roi rac', 'Theo ham'};
            app.KieunhapDropDown.ValueChangedFcn = createCallbackFcn(app, @Kieunhap, true);
            app.KieunhapDropDown.Value = 'Roi rac';
            app.PanelFunction = uipanel(app.TchphnTab);
            app.PanelFunction.Title = 'PanelFunction';
            app.NhaphamsoEditFieldLabel = uilabel(app.PanelFunction);
            app.NhaphamsoEditFieldLabel.Text = 'Nhap ham so :';
            app.NhaphamsoEditField = uieditfield(app.PanelFunction, 'text');
            app.NhaphamsoEditField.ValueChangedFcn = createCallbackFcn(app, @Nhaphamso, true);
            app.NhaphamsoEditField.Value = '1./x';
            app.CanduoiEditFieldLabel = uilabel(app.PanelFunction);
            app.CanduoiEditFieldLabel.Text = 'Can duoi :';
            app.CanduoiEditField = uieditfield(app.PanelFunction, 'numeric');
            app.CanduoiEditField.ValueChangedFcn = createCallbackFcn(app, @Canduoi, true);
            app.CanduoiEditField.Value = 1;
            app.NhapNEditFieldLabel = uilabel(app.PanelFunction);
            app.NhapNEditFieldLabel.Text = 'Nhap N:';
            app.NhapNEditField = uieditfield(app.PanelFunction, 'numeric');
            app.NhapNEditField.ValueChangedFcn = createCallbackFcn(app, @NhapN, true);
            app.NhapNEditField.Value = 4;
            app.CantrenEditFieldLabel = uilabel(app.PanelFunction);
            app.CantrenEditFieldLabel.Text = 'Can tren : ';
            app.CantrenEditField = uieditfield(app.PanelFunction, 'numeric');
            app.CantrenEditField.ValueChangedFcn = createCallbackFcn(app, @Cantren, true);
            app.CantrenEditField.Value = 5;
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.NTHCTHNHPHNGPHPTNHLabel = uilabel(app.GiithiunhmTab);
            app.NTHCTHNHPHNGPHPTNHLabel.Text = 'ĐỒ ÁN THỰC THÀNH PHƯƠNG PHÁP TÍNH';
            app.UITable2 = uitable(app.GiithiunhmTab);
            app.UITable2.ColumnName = {'Tên thành viên'; 'Tên github'; 'MSSV'; 'Lớp'};
            app.UITable2.RowName = {};
            app.LPhanThanhLimLabel = uilabel(app.GiithiunhmTab);
            app.LPhanThanhLimLabel.Text = 'Lê Phan Thanh Liêm ';
            app.BiGiaHuyLabel = uilabel(app.GiithiunhmTab);
            app.BiGiaHuyLabel.Text = 'Bùi Gia Huy';
            app.ngTriungKhoaLabel = uilabel(app.GiithiunhmTab);
            app.ngTriungKhoaLabel.Text = 'Đặng Triệu Đăng Khoa';
            app.NguynVitHngLabel = uilabel(app.GiithiunhmTab);
            app.NguynVitHngLabel.Text = 'Nguyễn Việt Hùng';
            app.NguynSinhHngLabel = uilabel(app.GiithiunhmTab);
            app.NguynSinhHngLabel.Text = 'Nguyễn Sinh Hùng';
            app.NguynNgcDngLabel = uilabel(app.GiithiunhmTab);
            app.NguynNgcDngLabel.Text = 'Nguyễn Ngọc Dũng';
            app.NguynThanhHuyLabel = uilabel(app.GiithiunhmTab);
            app.NguynThanhHuyLabel.Text = 'Nguyễn Thanh Huy';
            app.Label_2 = uilabel(app.GiithiunhmTab);
            app.Label_2.Text = '23207077';
            app.Label_3 = uilabel(app.GiithiunhmTab);
            app.Label_3.Text = '23207064';
            app.Label_4 = uilabel(app.GiithiunhmTab);
            app.Label_4.Text = '23207069';
            app.Label_5 = uilabel(app.GiithiunhmTab);
            app.Label_5.Text = '23207062';
            app.Label_6 = uilabel(app.GiithiunhmTab);
            app.Label_6.Text = '23207061';
            app.Label_7 = uilabel(app.GiithiunhmTab);
            app.Label_7.Text = '23207047';
            app.Label_8 = uilabel(app.GiithiunhmTab);
            app.Label_8.Text = '23207065';
            app.DTV_CLC2Label = uilabel(app.GiithiunhmTab);
            app.DTV_CLC2Label.Text = '23DTV_CLC2';
            app.DTV_CLC3Label = uilabel(app.GiithiunhmTab);
            app.DTV_CLC3Label.Text = '23DTV_CLC3';
            app.DTV_CLC3Label_2 = uilabel(app.GiithiunhmTab);
            app.DTV_CLC3Label_2.Text = '23DTV_CLC3';
            app.DTV_CLC2Label_2 = uilabel(app.GiithiunhmTab);
            app.DTV_CLC2Label_2.Text = '23DTV_CLC2';
            app.DTV_CLC3Label_3 = uilabel(app.GiithiunhmTab);
            app.DTV_CLC3Label_3.Text = '23DTV_CLC3';
            app.DTV_CLC3Label_4 = uilabel(app.GiithiunhmTab);
            app.DTV_CLC3Label_4.Text = '23DTV_CLC3';
            app.DTV_CLC3Label_5 = uilabel(app.GiithiunhmTab);
            app.DTV_CLC3Label_5.Text = '23DTV_CLC3';
            app.liemleeLabel = uilabel(app.GiithiunhmTab);
            app.liemleeLabel.Text = ' liemlee';
            app.MinWooUyLabel = uilabel(app.GiithiunhmTab);
            app.MinWooUyLabel.Text = 'MinWooUy';
            app.Dangkhoa280905Label = uilabel(app.GiithiunhmTab);
            app.Dangkhoa280905Label.Text = 'Dangkhoa280905';
            app.zhunnggLabel = uilabel(app.GiithiunhmTab);
            app.zhunnggLabel.Text = 'zhunngg';
            app.hngngynt2005byteLabel = uilabel(app.GiithiunhmTab);
            app.hngngynt2005byteLabel.Text = 'hngngynt2005-byte';
            app.dung21405Label = uilabel(app.GiithiunhmTab);
            app.dung21405Label.Text = 'dung21405';
            app.huy489Label = uilabel(app.GiithiunhmTab);
            app.huy489Label.Text = 'huy489';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_ppt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end