classdef tab_tich_phan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        faTab                    matlab.ui.container.Tab
        TICHPHANGANDUNGLabel     matlab.ui.control.Label
        PanelFunction            matlab.ui.container.Panel
        CantrenEditField         matlab.ui.control.NumericEditField
        CantrenEditFieldLabel    matlab.ui.control.Label
        NhapNEditField           matlab.ui.control.NumericEditField
        NhapNEditFieldLabel      matlab.ui.control.Label
        CanduoiEditField         matlab.ui.control.NumericEditField
        CanduoiEditFieldLabel    matlab.ui.control.Label
        NhaphamsoEditField       matlab.ui.control.EditField
        NhaphamsoEditFieldLabel  matlab.ui.control.Label
        PanelData                matlab.ui.container.Panel
        NhapyEditField           matlab.ui.control.EditField
        NhapyEditFieldLabel      matlab.ui.control.Label
        NhapxEditField           matlab.ui.control.EditField
        NhapxEditFieldLabel      matlab.ui.control.Label
        KieunhapDropDown         matlab.ui.control.DropDown
        KieunhapDropDownLabel    matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        KetquaButton             matlab.ui.control.Button
        LuachonDropDown          matlab.ui.control.DropDown
        LuachonDropDownLabel     matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        Tab2                     matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function y = TichPhanHinhThang(app, f, a, b, N)
        h = (b - a)/N;

        fa = f(a);
        fb = f(b);

        S = 0;
        for i = 1:N-1
            xi = a + i*h;
            S = S + f(xi);
        end

        y = h/2 * (fa + fb + 2*S);
        end  
    end
      
    
    methods (Access = private)
        
        function S = TichPhanHinhThangxy(app, x, y)
        N= (x(length(x))-x(1))/(x(2)-x(1));
        if length(x) ~= length(y)
        error('x và y phải có cùng số phần tử');
        end
        if N ~= length(x) - 1
            error('N phải bằng length(x) - 1');
        end
        lenx =length(x);
        h=(x(lenx)-x(1))/N; 

       
        S = y(1)+ y(lenx)+ 2*sum(y(2:(lenx-1)));
        S= h/2*S;
        end  
    end
  

    % ===== Simpson 1/3 cho ham so f(x) tren [a,b], N CHAN =====

    
    % ===== Simpson 3/8 cho ham so f(x) tren [a,b], N chia het cho 3 =====

    % ===== Simpson 1/3 cho du lieu (x,y) - x CACH DEU, N=length(x)-1 CHAN =====

    % ===== Simpson 3/8 cho du lieu (x,y) - x CACH DEU, N=length(x)-1 chia het cho 3 =====
    methods (Access = private)

    % ===== Simpson 1/3 cho ham so f(x) tren [a,b], N CHAN =====
    function I = TichPhanSimpson13(app, f, a, b, N)
        if mod(N,2) ~= 0
            error('Simpson 1/3: N phai chan');
        end
        h = (b-a)/N;
        x = a:h:b;
        y = f(x);
        I = h/3*(y(1)+y(end) + 4*sum(y(2:2:end-1)) + 2*sum(y(3:2:end-2)));
    end

    % ===== Simpson 3/8 cho ham so f(x) tren [a,b], N chia het cho 3 =====
    function I = TichPhanSimpson38(app, f, a, b, N)
        if mod(N,3) ~= 0
            error('Simpson 3/8: N phai chia het cho 3');
        end
        h = (b-a)/N;
        x = a:h:b;
        y = f(x);

        idx = 2:N;                       % cac chi so noi bo
        mult3 = idx(mod(idx,3)==0);       % chia het cho 3
        not3  = idx(mod(idx,3)~=0);       % khong chia het cho 3

        I = 3*h/8*( y(1) + y(end) + 2*sum(y(mult3)) + 3*sum(y(not3)) );
    end

    % ===== Simpson 1/3 cho du lieu (x,y) - x CACH DEU, N=length(x)-1 CHAN =====
    function I = TichPhanSimpson13xy(app, x, y)
        if length(x) ~= length(y)
            error('x va y phai co cung so phan tu');
        end
        x = x(:); y = y(:);
        N = length(x)-1;
        if mod(N,2) ~= 0
            error('Simpson 1/3 (xy): N=length(x)-1 phai chan');
        end

        h = x(2)-x(1);
        if any(abs(diff(x)-h) > 1e-12)
            error('Simpson (xy): x phai cach deu');
        end

        I = h/3*( y(1)+y(end) + 4*sum(y(2:2:end-1)) + 2*sum(y(3:2:end-2)) );
    end

    % ===== Simpson 3/8 cho du lieu (x,y) - x CACH DEU, N=length(x)-1 chia het cho 3 =====
    function I = TichPhanSimpson38xy(app, x, y)
        if length(x) ~= length(y)
            error('x va y phai co cung so phan tu');
        end
        x = x(:); y = y(:);
        N = length(x)-1;
        if mod(N,3) ~= 0
            error('Simpson 3/8 (xy): N=length(x)-1 phai chia het cho 3');
        end

        h = x(2)-x(1);
        if any(abs(diff(x)-h) > 1e-12)
            error('Simpson (xy): x phai cach deu');
        end

        idx = 2:N;
        mult3 = idx(mod(idx,3)==0);
        not3  = idx(mod(idx,3)~=0);

        I = 3*h/8*( y(1)+y(end) + 2*sum(y(mult3)) + 3*sum(y(not3)) );
    end


    function e = saisohinhthang_xy(app, x, y)
    % x, y: du lieu roi rac

    N = length(x) - 1;
    h = x(2) - x(1);

    % Xap xi f'' bang sai phan huu han
    f2 = diff(y, 2) / h^2;

    M2 = max(abs(f2));   % max |f''|

    e = (x(end) - x(1))^3 / (12 * N^2) * M2;
    end
    function e = saisohinhthang_f(app, f, a, b, N)
    % f : function handle @(x)
    % [a,b] : can tich phan
    % N : so doan

    x = linspace(a, b, 2000);
    y = f(x);

    h = x(2) - x(1);

    % Xap xi dao ham bac 2
    f2 = diff(y, 2) / h^2;

    M2 = max(abs(f2));   % max |f''|

    e = (b - a)^3 / (12 * N^2) * M2;
    end
    function e = saisoSimpson13_f(app, f, a, b, N)
    if mod(N,2) ~= 0
        error('Simpson 1/3: N phai chan');
    end

    % Lay mau day de xap xi dao ham bac 4
    x = linspace(a, b, 4000);
    y = f(x);

    h = x(2) - x(1);

    % Xap xi f'''' bang sai phan huu han
    f4 = diff(y, 4) / h^4;

    M4 = max(abs(f4));   % max |f''''|

    e = (b - a)^5 / (180 * N^4) * M4;
    end
    function e = saisoSimpson38_f(app, f, a, b, N)
    if mod(N,3) ~= 0
        error('Simpson 3/8: N phai chia het cho 3');
    end

    x = linspace(a, b, 4000);
    y = f(x);

    h = x(2) - x(1);

    f4 = diff(y, 4) / h^4;
    M4 = max(abs(f4));

    e = (b - a)^5 / (80 * N^4) * M4;
    end
    function e = saisoSimpson13_xy(app, x, y)
    N = length(x) - 1;

    if mod(N,2) ~= 0
        error('Simpson 1/3 (xy): N = length(x)-1 phai chan');
    end

    h = x(2) - x(1);

    % Xap xi dao ham bac 4
    f4 = diff(y, 4) / h^4;

    M4 = max(abs(f4));

    e = (x(end) - x(1))^5 / (180 * N^4) * M4;
    end
    function e = saisoSimpson38_xy(app, x, y)
    N = length(x) - 1;

    if mod(N,3) ~= 0
        error('Simpson 3/8 (xy): N =  phai chia het cho 3');
    end

    h = x(2) - x(1);

    f4 = diff(y, 4) / h^4;

    M4 = max(abs(f4));

    e = (x(end) - x(1))^5 / (80 * N^4) * M4;
end


end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KetquaButton
        function Ketqua(app, event)
            %Lay gia tri
            x_str     = app.NhapxEditField.Value;
            y_str     = app.NhapyEditField.Value;
            hamso_str = app.NhaphamsoEditField.Value;
            option    = app.LuachonDropDown.Value;

            a = app.CanduoiEditField.Value;
            b = app.CantrenEditField.Value;
            N = app.NhapNEditField.Value;
            %Xu li
            
            x = str2num(x_str);
            y = str2num(y_str);


    switch option
    case 'Hinh thang'
        if ~isempty(x) && ~isempty(y)
            S = app.TichPhanHinhThangxy(x, y);
            xplot = x; yplot = y;
        else
            fx = str2func("@(x)" + hamso_str);
            S  = app.TichPhanHinhThang(fx, a, b, N);
            xplot = linspace(a, b, N+1);
            yplot = fx(xplot);
        end
%start LIM 
        case 'Simpson1/3'
        if ~isempty(x) && ~isempty(y)
            % Simpson 1/3 voi du lieu: N = length(x)-1 phai chan
            try
                S = app.TichPhanSimpson13xy(x, y);
            catch ME
                uialert(app.UIFigure, ME.message, 'Loi Simpson 1/3 (xy)');
                return;
            end
            xplot = x; yplot = y;
        else
            if mod(N,2) ~= 0
                uialert(app.UIFigure,'Simpson 1/3 can N chan','Sai N');
                return;
            end
            fx = str2func("@(x)" + hamso_str);
            try
                S = app.TichPhanSimpson13(fx, a, b, N);
            catch ME
                uialert(app.UIFigure, ME.message, 'Loi Simpson 1/3');
                return;
            end
            xplot = linspace(a, b, N+1);
            yplot = fx(xplot);
        end

    case 'Simpson3/8'
        if ~isempty(x) && ~isempty(y)
            % Simpson 3/8 voi du lieu: N = length(x)-1 chia het cho 3
            try
                S = app.TichPhanSimpson38xy(x, y);
            catch ME
                uialert(app.UIFigure, ME.message, 'Loi Simpson 3/8 (xy)');
                return;
            end
            xplot = x; yplot = y;
        else
            if mod(N,3) ~= 0
                uialert(app.UIFigure,'Simpson 3/8 can N chia het cho 3','Sai N');
                return;
            end
            fx = str2func("@(x)" + hamso_str);
            try
                S = app.TichPhanSimpson38(fx, a, b, N);
            catch ME
                uialert(app.UIFigure, ME.message, 'Loi Simpson 3/8');
                return;
            end
            xplot = linspace(a, b, N+1);
            yplot = fx(xplot);
        end
        
        
    end
  %end LIM
        switch option
    case 'Hinh thang'
        if ~isempty(x) && ~isempty(y)
            % Sai so voi du lieu roi rac
            e = app.saisohinhthang_xy(x, y);
        else
            % Sai so theo ham
            fx = str2func("@(x)" + hamso_str);
            e  = app.saisohinhthang_f(fx, a, b, N);
        end

    case 'Simpson1/3'
        if ~isempty(x) && ~isempty(y)
            % Sai so Simpson 1/3 (xy)
            e = app.saisoSimpson13_xy(x, y);
        else
            % Sai so Simpson 1/3 (ham)
            fx = str2func("@(x)" + hamso_str);
            e  = app.saisoSimpson13_f(fx, a, b, N);
        end

    case 'Simpson3/8'
        if ~isempty(x) && ~isempty(y)
            % Sai so Simpson 3/8 (xy)
            e = app.saisoSimpson38_xy(x, y);
        else
            % Sai so Simpson 3/8 (ham)
            fx = str2func("@(x)" + hamso_str);
            e  = app.saisoSimpson38_f(fx, a, b, N);
        end
    end


        cla(app.UIAxes);
        hold(app.UIAxes, 'on');

        plot(app.UIAxes, xplot, yplot, 'LineWidth', 2);
        area(app.UIAxes, xplot, yplot, 'FaceAlpha', 0.3, 'EdgeColor', 'none');

        % Ve truc Ox theo dung [a,b]
        plot(app.UIAxes, [a b], [0 0], 'k', 'LineWidth', 1);

        grid(app.UIAxes, 'on');
        hold(app.UIAxes, 'off');

        app.Label.Text = sprintf('Gia tri tich phan gan dung = %.6f   |   Sai so ≈ %.2e', S, e);

            
        end

        % Value changed function: LuachonDropDown
        function ValueChangedFcn(app, event)
        
               
        end

        % Value changed function: NhapxEditField
        function Nhapx(app, event)
           
            
        end

        % Value changed function: NhapyEditField
        function Nhapy(app, event)
            
            
        end

        % Value changed function: NhaphamsoEditField
        function Nhaphamso(app, event)
           
            
        end

        % Value changed function: CantrenEditField
        function Cantren(app, event)
           
            
        end

        % Value changed function: NhapNEditField
        function NhapN(app, event)
          
            
        end

        % Value changed function: CanduoiEditField
        function Canduoi(app, event)
       
            
        end

        % Value changed function: KieunhapDropDown
        function Kieunhap(app, event)
    
    value = app.KieunhapDropDown.Value;

    if strcmp(value, 'Roi rac')
        app.NhapxEditField.Value  = '[1 2 3 4 5]';
        app.PanelData.Visible     = 'on';
        app.PanelFunction.Visible = 'off';
    else
        app.NhapxEditField.Value ='';
        app.PanelData.Visible     = 'off';
        app.PanelFunction.Visible = 'on';
    end
        
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.faTab = uitab(app.TabGroup);
            app.faTab.Title = 'fa';
            app.UIAxes = uiaxes(app.faTab);
            title(app.UIAxes, 'Phan dien tich ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LuachonDropDownLabel = uilabel(app.faTab);
            app.LuachonDropDownLabel.Text = 'Lua chon:';
            app.LuachonDropDown = uidropdown(app.faTab);
            app.LuachonDropDown.Items = {'Hinh thang', 'Simpson1/3', 'Simpson3/8'};
            app.LuachonDropDown.ValueChangedFcn = createCallbackFcn(app, @ValueChangedFcn, true);
            app.LuachonDropDown.Value = 'Hinh thang';
            app.KetquaButton = uibutton(app.faTab, 'push');
            app.KetquaButton.ButtonPushedFcn = createCallbackFcn(app, @Ketqua, true);
            app.KetquaButton.Text = 'Ket qua';
            app.Label = uilabel(app.faTab);
            app.Label.Text = '  ';
            app.KieunhapDropDownLabel = uilabel(app.faTab);
            app.KieunhapDropDownLabel.Text = 'Kieu nhap : ';
            app.KieunhapDropDown = uidropdown(app.faTab);
            app.KieunhapDropDown.Items = {'Roi rac', 'Theo ham'};
            app.KieunhapDropDown.ValueChangedFcn = createCallbackFcn(app, @Kieunhap, true);
            app.KieunhapDropDown.Value = 'Roi rac';
            app.PanelData = uipanel(app.faTab);
            app.PanelData.Title = 'PanelData';
            app.NhapxEditFieldLabel = uilabel(app.PanelData);
            app.NhapxEditFieldLabel.Text = 'Nhap x: ';
            app.NhapxEditField = uieditfield(app.PanelData, 'text');
            app.NhapxEditField.ValueChangedFcn = createCallbackFcn(app, @Nhapx, true);
            app.NhapxEditField.Value = '[1 2 3 4 5]';
            app.NhapyEditFieldLabel = uilabel(app.PanelData);
            app.NhapyEditFieldLabel.Text = 'Nhap y: ';
            app.NhapyEditField = uieditfield(app.PanelData, 'text');
            app.NhapyEditField.ValueChangedFcn = createCallbackFcn(app, @Nhapy, true);
            app.NhapyEditField.Value = '[1 1/2 1/3 1/4 1/5]';
            app.PanelFunction = uipanel(app.faTab);
            app.PanelFunction.Title = 'PanelFunction';
            app.PanelFunction.Visible = 'off';
            app.NhaphamsoEditFieldLabel = uilabel(app.PanelFunction);
            app.NhaphamsoEditFieldLabel.Text = 'Nhap ham so :';
            app.NhaphamsoEditField = uieditfield(app.PanelFunction, 'text');
            app.NhaphamsoEditField.ValueChangedFcn = createCallbackFcn(app, @Nhaphamso, true);
            app.NhaphamsoEditField.Value = '1./x';
            app.CanduoiEditFieldLabel = uilabel(app.PanelFunction);
            app.CanduoiEditFieldLabel.Text = 'Can duoi :';
            app.CanduoiEditField = uieditfield(app.PanelFunction, 'numeric');
            app.CanduoiEditField.ValueChangedFcn = createCallbackFcn(app, @Canduoi, true);
            app.CanduoiEditField.Value = 1;
            app.NhapNEditFieldLabel = uilabel(app.PanelFunction);
            app.NhapNEditFieldLabel.Text = 'Nhap N:';
            app.NhapNEditField = uieditfield(app.PanelFunction, 'numeric');
            app.NhapNEditField.ValueChangedFcn = createCallbackFcn(app, @NhapN, true);
            app.NhapNEditField.Value = 4;
            app.CantrenEditFieldLabel = uilabel(app.PanelFunction);
            app.CantrenEditFieldLabel.Text = 'Can tren : ';
            app.CantrenEditField = uieditfield(app.PanelFunction, 'numeric');
            app.CantrenEditField.ValueChangedFcn = createCallbackFcn(app, @Cantren, true);
            app.CantrenEditField.Value = 5;
            app.TICHPHANGANDUNGLabel = uilabel(app.faTab);
            app.TICHPHANGANDUNGLabel.Text = 'TICH PHAN GAN DUNG';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tab_tich_phan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end